classdef NV_ExperimentEditor < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                        matlab.ui.Figure
      GridLayout                      matlab.ui.container.GridLayout
      LeftPanel                       matlab.ui.container.Panel
      TabGroup                        matlab.ui.container.TabGroup
      ConnectionsTab                  matlab.ui.container.Tab
      GridLayout2                     matlab.ui.container.GridLayout
      WindfreakRFLoad                 matlab.ui.control.Button
      WindfreakRFLamp                 matlab.ui.control.Lamp
      WindfreakRFUnload               matlab.ui.control.Button
      WindfreakRFConfig               matlab.ui.control.EditField
      WindfreakRFLabel                matlab.ui.control.Label
      DAQConfig                       matlab.ui.control.EditField
      SRSRFConfig                     matlab.ui.control.EditField
      SproutLaserConfig               matlab.ui.control.EditField
      PulseBlasterConfig              matlab.ui.control.EditField
      StageConfig                     matlab.ui.control.EditField
      ConfigFileNameLabel             matlab.ui.control.Label
      DAQLabel                        matlab.ui.control.Label
      SRSRFLabel                      matlab.ui.control.Label
      SproutLaserLabel                matlab.ui.control.Label
      PulseBlasterLabel               matlab.ui.control.Label
      StageLabel                      matlab.ui.control.Label
      SproutLaserLamp                 matlab.ui.control.Lamp
      SproutLaserUnload               matlab.ui.control.Button
      SproutLaserLoad                 matlab.ui.control.Button
      DAQUnload                       matlab.ui.control.Button
      DAQLoad                         matlab.ui.control.Button
      SRSRFUnload                     matlab.ui.control.Button
      PulseBlasterUnload              matlab.ui.control.Button
      DAQLamp                         matlab.ui.control.Lamp
      SRSRFLamp                       matlab.ui.control.Lamp
      PulseBlasterLamp                matlab.ui.control.Lamp
      SRSRFLoad                       matlab.ui.control.Button
      PulseBlasterLoad                matlab.ui.control.Button
      StageLamp                       matlab.ui.control.Lamp
      StageUnload                     matlab.ui.control.Button
      StageLoad                       matlab.ui.control.Button
      SettingsTab                     matlab.ui.container.Tab
      GridLayout4                     matlab.ui.container.GridLayout
      AmplitudedBmLabel               matlab.ui.control.Label
      WindfreakRFsettingsLabel        matlab.ui.control.Label
      SRSRFsettingsLabel              matlab.ui.control.Label
      windfreakRFModulationDropDown   matlab.ui.control.DropDown
      ModulationLabel                 matlab.ui.control.Label
      windfreakRFAmplitude            matlab.ui.control.NumericEditField
      windfreakRFFrequency            matlab.ui.control.NumericEditField
      RFFrequencyGHzLabel_2           matlab.ui.control.Label
      NumberofpointsforcontinuouscollectionEditField  matlab.ui.control.NumericEditField
      NumberofpointsforcontinuouscollectionLabel  matlab.ui.control.Label
      SRS_RFModulationDropDown        matlab.ui.control.DropDown
      OffWhenDeleted                  matlab.ui.control.CheckBox
      WaitForLaserEquilibrium         matlab.ui.control.CheckBox
      LaunchPulseSequenceEditorButton  matlab.ui.control.Button
      DifferentiatesignalandreferenceCheckBox  matlab.ui.control.CheckBox
      SRS_RFAmplitude                 matlab.ui.control.NumericEditField
      SRS_RFFrequency                 matlab.ui.control.NumericEditField
      CopyappobjecttoworkspaceButton  matlab.ui.control.Button
      CollectdatacontinuouslyButton   matlab.ui.control.Button
      RefreshLaserPowerReading        matlab.ui.control.Button
      LaserPowerReading               matlab.ui.control.NumericEditField
      PowerreadingmWLabel             matlab.ui.control.Label
      SetpowermWLabel                 matlab.ui.control.Label
      RefreshAppButton                matlab.ui.control.Button
      ToggleLaser                     matlab.ui.control.Switch
      LaserLabel_2                    matlab.ui.control.Label
      SetLaserPower                   matlab.ui.control.NumericEditField
      PositionTab                     matlab.ui.container.Tab
      GridLayout3                     matlab.ui.container.GridLayout
      OptimizationparameterDropDown   matlab.ui.control.DropDown
      OptimizationparameterLabel      matlab.ui.control.Label
      ZFineReset                      matlab.ui.control.Button
      YFineReset                      matlab.ui.control.Button
      XFineReset                      matlab.ui.control.Button
      ResetFineAxisLabel              matlab.ui.control.Label
      StageSettlingTime               matlab.ui.control.NumericEditField
      PausedurationforfinestagemovementsmsLabel  matlab.ui.control.Label
      OptimizationmethodDropDown      matlab.ui.control.DropDown
      OptimizationmethodDropDownLabel  matlab.ui.control.Label
      NumberofmanualoptimizationsEditField  matlab.ui.control.NumericEditField
      NumberofmanualoptimizationsEditFieldLabel  matlab.ui.control.Label
      BeginmanualoptimizationButton   matlab.ui.control.Button
      StageOptimizationInterval       matlab.ui.control.NumericEditField
      TimebetweenoptimizationsminLabel  matlab.ui.control.Label
      StageAutoOptimization           matlab.ui.control.CheckBox
      DeleteOptimizationStep          matlab.ui.control.Button
      AddOptimizationStep             matlab.ui.control.Button
      StageOptimizationInstructions   matlab.ui.control.Table
      StageAddress                    matlab.ui.control.NumericEditField
      AddressEditField_2Label         matlab.ui.control.Label
      ZIncrement                      matlab.ui.control.NumericEditField
      ZPosition                       matlab.ui.control.NumericEditField
      ZUp                             matlab.ui.control.Button
      YUp                             matlab.ui.control.Button
      ZDown                           matlab.ui.control.Button
      YDown                           matlab.ui.control.Button
      MovepositiveLabel               matlab.ui.control.Label
      MovenegativeLabel               matlab.ui.control.Label
      StagezLabel                     matlab.ui.control.Label
      StageyLabel                     matlab.ui.control.Label
      StagexLabel                     matlab.ui.control.Label
      AxisLabel                       matlab.ui.control.Label
      StageTolerance                  matlab.ui.control.NumericEditField
      StagetolerancemLabel            matlab.ui.control.Label
      YPosition                       matlab.ui.control.NumericEditField
      YIncrement                      matlab.ui.control.NumericEditField
      IncrementLabel                  matlab.ui.control.Label
      AbsolutepositionLabel           matlab.ui.control.Label
      XPosition                       matlab.ui.control.NumericEditField
      XIncrement                      matlab.ui.control.NumericEditField
      XUp                             matlab.ui.control.Button
      XDown                           matlab.ui.control.Button
      ScansTab                        matlab.ui.container.Tab
      GridLayout6                     matlab.ui.container.GridLayout
      NonlinearscanfilenameEditField  matlab.ui.control.EditField
      NonlinearscanfilenameLabel      matlab.ui.control.Label
      IterationstypeDropDown          matlab.ui.control.DropDown
      IterationstypeDropDownLabel     matlab.ui.control.Label
      MaxIterations                   matlab.ui.control.NumericEditField
      MaxIterationsEditFieldLabel     matlab.ui.control.Label
      RandomizescanCheckBox           matlab.ui.control.CheckBox
      PossibletypesTextArea           matlab.ui.control.TextArea
      PossibletypesTextAreaLabel      matlab.ui.control.Label
      DefaultScanChange               matlab.ui.control.DropDown
      DefaultchangeDropDownLabel      matlab.ui.control.Label
      ScanAddress                     matlab.ui.control.NumericEditField
      AddressEditField_3Label         matlab.ui.control.Label
      DeleteScanStep                  matlab.ui.control.Button
      AddScanStep                     matlab.ui.control.Button
      ScanningTable                   matlab.ui.control.Table
      OutputTab                       matlab.ui.container.Tab
      GridLayout5                     matlab.ui.container.GridLayout
      ForceplotupdateButton           matlab.ui.control.Button
      ConverttocountssMaynotperfectlycalculateforsmalltimesCheckBox  matlab.ui.control.CheckBox
      ContrasttypeDropDown            matlab.ui.control.DropDown
      ContrasttypeDropDownLabel       matlab.ui.control.Label
      ScannumberofyaxisEditField      matlab.ui.control.NumericEditField
      ScannumberofyaxisEditFieldLabel  matlab.ui.control.Label
      ScannumberofxaxisEditField      matlab.ui.control.NumericEditField
      ScannumberofxaxisEditFieldLabel  matlab.ui.control.Label
      StaticColorLimits               matlab.ui.control.Table
      UseStaticColorLimits            matlab.ui.control.CheckBox
      DropDown                        matlab.ui.control.DropDown
      ForcesquareaspectratioforimagesCheckBox  matlab.ui.control.CheckBox
      ScanProgress                    matlab.ui.control.TextArea
      ScanStepTable                   matlab.ui.control.Table
      PlotLimits                      matlab.ui.control.Table
      SaveLoadTab                     matlab.ui.container.Tab
      GridLayout9                     matlab.ui.container.GridLayout
      UserName                        matlab.ui.control.DropDown
      LoadNames                       matlab.ui.control.ListBox
      LoadDates                       matlab.ui.control.ListBox
      DataSetupsSwitch                matlab.ui.control.Switch
      SavedUserNotes                  matlab.ui.control.TextArea
      LoadButton                      matlab.ui.control.Button
      SaveButton                      matlab.ui.control.Button
      SaveFolderName                  matlab.ui.control.EditField
      SavenameLabel                   matlab.ui.control.Label
      BoxAddress                      matlab.ui.control.EditField
      BoxaddressEditFieldLabel        matlab.ui.control.Label
      RightPanel                      matlab.ui.container.Panel
      Panel                           matlab.ui.container.Panel
      GridLayout7                     matlab.ui.container.GridLayout
      AbortButton                     matlab.ui.control.Button
      ScreenshotButton                matlab.ui.control.Button
      StatusLamp                      matlab.ui.control.Lamp
      ColorBarPanel                   matlab.ui.container.Panel
      OutputInfo                      matlab.ui.control.TextArea
      ResetButton                     matlab.ui.control.Button
      StartButton                     matlab.ui.control.Button
      OutputPanel                     matlab.ui.container.Panel
      StageOptimizationInstructions_2  matlab.ui.control.Table
   end

   % Properties that correspond to apps with auto-reflow
   properties (Access = private)
      onePanelWidth = 576;
   end

    properties
       userList = {'Common','Mikael','David','Lakshmy','Kyle','Swastik','Aksshay'}
        ex = experiment() %Contains information necesary to run experiment as well as all the instrument objects
        storedDataSettings
        scanStatus = 'reset'
        videoRunning = false
        optimizationInfo       
        displayInfo        
        general
    end

    methods (Access = private)

        %Current reflections and deliberations

        %Replace app.ex.scan.completed with
        %app.storedDataSettings.completed because user does not
        %control this and it is only relevant for whatever data is currently
        %stored

        %Likewise for .scan.stepToView, it is only relevant for display so I
        %should really put it in .display.stepToView

        %All stored settings necessary to interpret data:
        %bounds usage
        %frame stack usage
        %which step was most recently completed (not output display, just
        %info shown to user in outputSettings tab)
        %scan info: type, nSteps, start, end, and optionally stepSize


        %Notes regarding this app and its associated functions

        %First, I use a global variable - master - for holding all
        %information I wish to maintain between various functions. I am aware
        %global variables are generally frowned upon, however, after
        %analyzing my options, I found it to be the simplest and most
        %flexible solution. It is critical that every function be "on the
        %same page" and, quite often, those functions reference a whole host
        %of variables. It is possible that I could have instead used a
        %variable to hold all the information and used that as an argument in
        %every function I wrote but at that point it is just a global
        %variable by a different name.

        %Second, this is my second time developing an instrument-software app
        %like this. I have learned quite a few lessons from my first attempt
        %and this is significantly better for it. That said, there are still
        %many things I would do differently if I were to begin anew but, for
        %this app, have already been baked into its foundations too much to
        %easily change. My guiding goals when making this app were
        %simplicity, flexibility, and user friendliness. My previous attempt
        %was certainly spaghetti code; it was nearly impossible to weave
        %through and understand in its entirity, a state I wish to resolve
        %here. My previous code also continually ran into issues where new
        %features needed to be added but I was unable to integrate them
        %without essentially breaking everything or making something heavily
        %suboptimal. Finally, I am actually employing graphic design
        %principles in this app (at least to some degree) and I am trying to
        %make the process of learning the app easier.

        %Third, you will quickly notice that my variables could charitably be
        %called "thorough". I made this change as there are simply too many
        %variables throughout this for me to adequately keep track of and it
        %is thus necessary to refresh myself whenever I wished to employ
        %them. I found abbreviations were both annoying to remember and
        %difficult to teach to others. While my variables are long, they are
        %always very descriptive of what they represent. For less complex and
        %interwoven code bases I would be much more likely to simplify
        %variable names to make typing it out easier but, as mentioned
        %previously, there is altogether too much to keep track of as it is.

        %Fourth, much of the features within this app and its associated
        %functions were developed due to requests from users. As such, they
        %may be niche or otherwise somewhat odd, but this app was made to be
        %used by our lab and so I attempted to make it as useful to the
        %operators as possible. If anyone from outside of the Backlund lab at
        %UIUC is reading this, first of all you will need to greatly adjust
        %these functions to meet your instrument needs as this was developed
        %for the exact instruments we are using; additionally, you may wish
        %to add or remove features depending on what your users desire.
        %Nothing is sacred within this code base, alter it as you wish.
        %Though this comes with the warning that some alterations may have
        %unexpected side-effects elsewhere.

        %Fifth, as you can see from the above, I have attempted to be very
        %thorough with my comments. You will find that even relatively simple
        %processes are described in plain english. This is partly to assist
        %myself with keeping track of everything but mostly it is for any new
        %programmers working with this code. I hope you appreciate it :)

        function AppUpdate(app)
            %Updates all pages of the app
            ScanUpdate(app)
            PositionUpdate(app)
            ConnectionsUpdate(app)
            GeneralUpdate(app)
            SaveDataUpdate(app)
            StatusUpdate(app)
            OutputSettingsUpdate(app)
        end

        function app = setOptimizationInfo(app)
            app.ex.optimizationInfo.timeBetweenOptimizations = 1;
        end


        function ConnectionsUpdate(app)

            %Checks connected status of each instrument and makes corresponding lamp red (unconnected) or green (connected)
            if checkInstrumentStatus(app,'PIstage')
                app.StageLamp.Color = [0.25,0.61,0.25];%on, green
            else
                app.StageLamp.Color = [0.78,0.09,0.22];%off, red
            end

            if checkInstrumentStatus(app,'pulseBlaster')
                app.PulseBlasterLamp.Color = [0.25,0.61,0.25];
            else
                app.PulseBlasterLamp.Color = [0.78,0.09,0.22];
            end

            % if checkInstrumentStatus(app,'ndYAG')
                 % app.SproutLaserLamp.Color = [0.25,0.61,0.25];
            % else
                app.SproutLaserLamp.Color = [0.78,0.09,0.22];
            % end

            if checkInstrumentStatus(app,'SRS_RF')
                app.SRSRFLamp.Color = [0.25,0.61,0.25];
            else
                app.SRSRFLamp.Color = [0.78,0.09,0.22];
            end

            if checkInstrumentStatus(app,'windfreak_RF')
                app.WindfreakRFLamp.Color = [0.25,0.61,0.25];
            else
                app.WindfreakRFLamp.Color = [0.78,0.09,0.22];
            end

            if checkInstrumentStatus(app,'DAQ')
                app.DAQLamp.Color = [0.25,0.61,0.25];
            else
                app.DAQLamp.Color = [0.78,0.09,0.22];
            end

        end

        function GeneralUpdate(app)
            %For each instrument, check if instrument is connected and, if not, display default values. If it is connected,
            %change the display of each option to the current setting
            %For binary settings (on/off), value from instrument object is converted into state usable by display method
            % if checkInstrumentStatus(app,'ndYAG')
            %     app.OffWhenDeleted.Value = instrumentType.discernOnOff(app.ex.ndYAG.offWhenDeleted);
            %     app.WaitForLaserEquilibrium.Value = instrumentType.discernOnOff(app.ex.ndYAG.waitForEquilibrium);
            %     app.ToggleLaser.Value = instrumentType.discernOnOff(app.ex.ndYAG.enabled);
            %     app.SetLaserPower.Value = app.ex.ndYAG.setPower;
            %     app.LaserPowerReading.Value = app.ex.ndYAG.actualPower;
            % else
            %     app.OffWhenDeleted.Value = false;
            %     app.WaitForLaserEquilibrium.Value = false;
            %     app.ToggleLaser.Value = 'off';
            %     app.SetLaserPower.Value = 0;
            %     app.LaserPowerReading.Value = false;
            % end

            if checkInstrumentStatus(app,'SRS_RF')
                app.SRS_RFFrequency.Value = app.ex.SRS_RF.frequency;
                app.SRS_RFAmplitude.Value = app.ex.SRS_RF.amplitude;
                if strcmpi("On",app.ex.SRS_RF.modulationEnabled)
                    app.SRS_RFModulationDropDown.Value = app.ex.SRS_RF.modulationType;
                else
                    app.SRS_RFModulationDropDown.Value = "Off";
                end
            else
                app.SRS_RFFrequency.Value = 0;
                app.SRS_RFAmplitude.Value = 0;
                app.SRS_RFModulationDropDown.Value = 'Off';
            end

            if checkInstrumentStatus(app,'windfreak_RF')
                app.windfreakRFFrequency.Value = app.ex.windfreak_RF.frequency;
                app.windfreakRFAmplitude.Value = app.ex.windfreak_RF.amplitude;
                if strcmpi("On",app.ex.windfreak_RF.modulationEnabled)
                    app.windfreakRFModulationDropDown.Value = app.ex.windfreak_RF.modulationType;
                else
                    app.windfreakRFModulationDropDown.Value = "Off";
                end
            else
                app.windfreakRFFrequency.Value = 0;
                app.windfreakRFAmplitude.Value = 0;
                app.windfreakRFModulationDropDown.Value = 'Off';
            end
        end

        function SaveDataUpdate(app)
            app.SaveFolderName.Value = app.SaveFolderName.Value;

            app.BoxAddress.Value = app.BoxAddress.Value;

            app.SavedUserNotes.Value = app.SavedUserNotes.Value;

            app.UserName.Value = app.UserName.Value;

            app.DataSetupsSwitch.Value = app.DataSetupsSwitch.Value;


            %Wants to remove space between SM and data/setups
            rootName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',...
                app.UserName.Value, ['\Experiment Controller SM ',app.DataSetupsSwitch.Value]);
            firstDir = dir(rootName);
            if numel(firstDir) >= 3
                firstDir = struct2cell(firstDir(3:end));
                recencyOrder = datetime(firstDir(3,:));
                [~,idx] = sort(recencyOrder,'descend');
                firstNames = firstDir(1,:);
                firstNames = firstNames(idx);
                firstNames(2:end+1) = firstNames;

            else
                app.LoadNames.Items = {[app.DataSetupsSwitch.Value,' Names']};
                app.LoadDates.Items = {[app.DataSetupsSwitch.Value,' Dates']};
                app.LoadNames.Value = [app.DataSetupsSwitch.Value,' Names'];
                app.LoadDates.Value = [app.DataSetupsSwitch.Value,' Dates'];
                return
            end

            if strcmp(app.DataSetupsSwitch.Value,'Data')
                %List all dates
                firstNames{1} = [app.DataSetupsSwitch.Value,' Dates'];
                app.LoadDates.Items = firstNames;
                rootName = strcat(rootName,'\',app.LoadDates.Value);

            else %Setups
                %List all names
                firstNames{1} = [app.DataSetupsSwitch.Value,' Names'];
                app.LoadNames.Items = firstNames;
                rootName = strcat(rootName,'\',app.LoadNames.Value);
            end

            secondDir = dir(rootName);

            if numel(secondDir) >= 3
                secondDir = struct2cell(secondDir(3:end));
                recencyOrder = datetime(secondDir(3,:));
                [~,idx] = sort(recencyOrder,'descend');
                secondNames = secondDir(1,:);
                secondNames = secondNames(idx);
                secondNames(2:end+1) = secondNames;


                if strcmp(app.DataSetupsSwitch.Value,'Data')
                    secondNames{1} = [app.DataSetupsSwitch.Value,' Names'];
                    app.LoadNames.Items = secondNames;
                else
                    secondNames{1} = [app.DataSetupsSwitch.Value,' Dates'];
                    app.LoadDates.Items = secondNames;
                end

            elseif strcmp(app.DataSetupsSwitch.Value,'Data')
                app.LoadNames.Items = {[app.DataSetupsSwitch.Value,' Names']};
            else
                app.LoadDates.Items = {[app.DataSetupsSwitch.Value,' Dates']};
            end

            if ~ismember(app.LoadNames.Value,app.LoadNames.Items)
                app.LoadNames.Value = [app.DataSetupsSwitch.Value,' Names'];
            end

            app.LoadNames.Value = app.LoadNames.Value;

            if ~ismember(app.LoadDates.Value,app.LoadDates.Items)
                app.LoadDates.Value = [app.DataSetupsSwitch.Value,' Dates'];
            end

            app.LoadDates.Value = app.LoadDates.Value;
            %Check if selection is not just default

            %If it isn't, repeat process from above


            %          %Find the folder for data from this app
            %          rootSaveName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\SM ExperimentController');
            %          dateList = struct2cell(dir(rootSaveName));
            %          dateList = dateList(1,:); %Only looks at name row
            %
            %          %If no folders appear, the box address is almost certainly the
            %          %issue
            %          if isempty(dateList)
            %             return
            %          end
            %
            %          %First two are always useless nothings
            %          dateList = dateList(:,3:end);
            %
            %          %Probably doesn't actually need to be sorted because they are
            %          %already saved in order of date but it doesn't hurt
            %          [~,idx] = sort(dateList);
            %          dateList = dateList(idx);
            %
            %          %Shift everything by 1 to allow for a header
            %          dateList(2:end+1) = dateList;
            %          dateList{1} = 'Dates:';
            %
            %          %Displays a list of all folders
            %          app.SavedDates.Value = dateList;
            %
            %          %If the designated date is not among the dates that have folders,
            %          %end this function
            %          if ~contains(dateList,string(app.LoadDates.Value))
            %             return
            %          end
            %
            %          %Add the load date to the save name then finds folders within
            %          rootSaveName = strcat(rootSaveName,'/',string(app.LoadDates.Value));
            %          folderList = struct2cell(dir(rootSaveName));
            %          folderList = folderList(1,:); %Only looks at name row
            %
            %          %If there are only 2 elements, there are no actual folders present
            %          if numel(folderList) < 3
            %             app.SavedFolders.Value = 'Folder Names:';
            %             return
            %          end
            %
            %          %First two are always useless nothings
            %          folderList = folderList(:,3:end);
            %
            %          %Shift everything by 1 to allow for a header
            %          folderList(2:end+1) = folderList;
            %          folderList{1} = 'Folder Names:';
            %
            %          %Displays a list of all folders
            %          app.SavedFolders.Value = folderList;


        end

        function PositionUpdate(app)
            %Updates the absolute position of stage axes
            if checkInstrumentStatus(app,'PIstage')
                app.XPosition.Value = app.ex.PIstage.axisSum{strcmp(app.ex.PIstage.axisSum(:,1),'X'),2};
                app.YPosition.Value = app.ex.PIstage.axisSum{strcmp(app.ex.PIstage.axisSum(:,1),'Y'),2};
                app.ZPosition.Value = app.ex.PIstage.axisSum{strcmp(app.ex.PIstage.axisSum(:,1),'Z'),2};
            end

            % app.StageoptimizationDropDown.Value = app.ex.optimizationInfo.algorithmType;
            if ~isempty(app.ex.PIstage)
                app.StageTolerance.Value = app.ex.PIstage.tolerance;
            end

            app.StageAddress.Value = app.stageAddress.Value;

            % app.StageAutoOptimization.Value = app.ex.stage.autoStageOptimize;

            app.StageOptimizationInterval.Value = app.ex.optimizationInfo.timeBetweenOptimizations;

            % app.StageOptimizationInstructions.Data = [app.ex.optimizationInfo.stageAxes' app.ex.optimizationInfo.radius' app.ex.optimizationInfo.steps'];

        end

        function OutputSettingsUpdate(app)

            app.ForcesquareaspectratioforimagesCheckBox.Value = app.displayInfo.squareRatio;

            app.DropDown.Value = app.displayInfo.dataSelection;

            lowBounds = string(app.displayInfo.limitsIfOverriden{1,2}(1));
            lowBounds(2) = string(app.displayInfo.limitsIfOverriden{2,2}(1));
            lowBounds(3) = string(app.displayInfo.limitsIfOverriden{3,2}(1));
            highBounds = string(app.displayInfo.limitsIfOverriden{1,2}(2));
            highBounds(2) = string(app.displayInfo.limitsIfOverriden{2,2}(2));
            highBounds(3) = string(app.displayInfo.limitsIfOverriden{3,2}(2));

            for ii = 1:size(app.displayInfo.overrideLimits,1)
                if app.displayInfo.overrideLimits{ii,2}
                    overrideLimits(ii) = "true"; %#ok<AGROW>
                else
                    overrideLimits(ii) = "false"; %#ok<AGROW>
                end
            end


            app.PlotLimits.Data = [["x";"y";"color"] lowBounds' highBounds' overrideLimits'];

            app.ScanStepTable.Data = [app.storedDataSettings.scan.type' app.storedDataSettings.scan.stepToView'];

            if ~app.ex.randomizeScanPoints
                scanProgress = cell(1,app.storedDataSettings.nScans);
                for ii = 1:app.storedDataSettings.nScans
                    scanProgress{ii} = sprintf('%s %d/%d',app.storedDataSettings.scan.type(ii),...
                        app.storedDataSettings.completed(ii),app.storedDataSettings.scan.nSteps(ii));
                end
            else
                scanProgress = {sprintf('%d/%d total steps',app.ex.odometer,...
                    prod(app.storedDataSettings.scan.nSteps))};
            end
            app.ScanProgress.Value = scanProgress;

            %Must be done individually because it would make a single string
            %rather than a string arrray
            colorLimits(1) = convertCharsToStrings(num2str(app.displayInfo.staticColorLimits(1)));
            colorLimits(2) = convertCharsToStrings(num2str(app.displayInfo.staticColorLimits(2)));

            app.StaticColorLimits.Data = [["minimum";"maximum"] colorLimits'];

            app.UseStaticColorLimits.Value = app.displayInfo.useStaticColorLimits;

            %DisplayUpdate(app)

        end

        function DisplayUpdate(app)
            %display only the data that has been chosen, leave the regular
            %data unchanged

            %

            %If no data is present, don't execute this function
            if isempty(app.ex)
                return
            end

            cellDisplayed = num2cell(app.ex.scan.stepToView);

            %Gets the relevant cell from the data array
            displayData = app.storedData{cellDisplayed{:}};

            %FIX THIS FOR SECOND BOUNDS DISPLAY
            if isempty(displayData)
                return %No data in the current cell to display
            else
                %Sets the data to the correct matrix within the data cell
                %array dependent on what is to be shown
                switch app.displayInfo.dataSelection
                    case 'Average'
                        displayData = displayData{4,1};
                    case 'Current'
                        displayData = displayData{2,1};
                    case 'Prior'
                        %Prior data is just added together so to get a proper
                        %measure it must be divided by the number of prior
                        %iterations
                        displayData = displayData{1,1} ./ (displayData{3,1}-1);
                end
            end

            %Takes the log of the data if desired
            if app.displayInfo.logarithmicContrast
                displayData = log(displayData);
            end

            %If the current data and limits are the same as the previous,
            %end this function
            if isfield(app.displayInfo,'previousData') && ...
                    all(all(displayData ~= app.displayInfo.previousData)) && ...
                    ~app.displayInfo.alteredLimits

                return
            end

            if ~isfield(app.displayInfo,'axes')
                %If no image has been made yet, generate one "normally"
                app.displayInfo.axes = axes(app.OutputPanel);
                app.displayInfo.image = imagesc(app.displayInfo.axes,displayData);
                colormap(app.displayInfo.axes,"gray")
            else
                %Just edit data to prevent memory leak which will occur if
                %image is continually overwritten
                app.displayInfo.image.CData = displayData;
            end

            %Alter x/y limits if needed
            %+/- .5 comes from how matlab displays images. If not
            %included, the first and last points will be cut in half
            if app.displayInfo.overrideLimits{1,2} %x
                % app.displayInfo.axes.XLimMode = 'manual';
                app.displayInfo.axes.XLim(1) = app.displayInfo.limitsIfOverriden{1,2}(1) - .5;
                app.displayInfo.axes.XLim(2) = app.displayInfo.limitsIfOverriden{1,2}(2) + .5;
            else
                % app.displayInfo.axes.XLimMode = 'auto';
                app.displayInfo.axes.XLim(1) = 1 - .5;
                app.displayInfo.axes.XLim(2) = size(displayData,2) + .5;
            end
            if app.displayInfo.overrideLimits{2,2} %y
                % app.displayInfo.axes.YLimMode = 'manual';
                app.displayInfo.axes.YLim(1) = app.displayInfo.limitsIfOverriden{2,2}(1) - .5;
                app.displayInfo.axes.YLim(2) = app.displayInfo.limitsIfOverriden{2,2}(2) + .5;
            else
                % app.displayInfo.axes.YLimMode = 'auto';
                app.displayInfo.axes.YLim(1) = 1 - .5;
                app.displayInfo.axes.YLim(2) = size(displayData,1) + .5;
            end


            if app.displayInfo.overrideLimits{3,2} %color
                if ~app.displayInfo.useStaticColorLimits
                    %Color limits are based on percentiles rather than specific
                    %numbers. This means limits of 0 and 100 show default colors
                    %while 50 and 75 means the bottom 50% of points are all the
                    %lowest color while the top 25% are all the highest color with
                    %all the variation in between those values
                    lowLimit = prctile(displayData,app.displayInfo.limitsIfOverriden{3,2}(1),"all");
                    highLimit = prctile(displayData,app.displayInfo.limitsIfOverriden{3,2}(2),"all");
                else
                    %Invalidate the above and just make them a static number.
                    %Useful primarily for videos desiring a constant color limit
                    lowLimit = app.displayInfo.staticColorLimits(1);
                    highLimit = app.displayInfo.staticColorLimits(2);
                end
            else
                lowLimit = min(displayData,[],"all");
                highLimit = max(displayData,[],"all");
            end

            %Adjusts the upper limit temporarily to let lower limit be
            %established
            if lowLimit > app.displayInfo.axes.CLim(2)
                app.displayInfo.axes.CLim(2) = lowLimit + .01;
            end
            %Sets color limits based on previously obtained values
            app.displayInfo.axes.CLim(1) = lowLimit;
            app.displayInfo.axes.CLim(2) = highLimit;


            %Changes aspect ratio of the data to be square which makes the
            %side lengths equivalent for each pixel
            %Only first 2 numbers (x and y) matter, third is for z axis which
            %isn't present
            if app.displayInfo.squareRatio && strcmp(app.displayInfo.axes.DataAspectRatioMode,'auto')
                app.displayInfo.axes.DataAspectRatio = [1 1 1];
            elseif ~app.displayInfo.squareRatio && ~strcmp(app.displayInfo.axes.DataAspectRatioMode,'auto')
                app.displayInfo.axes.DataAspectRatioMode = 'auto';
            end

            %Sets previous displayed data and limits to what was just shown
            app.displayInfo.previousData = displayData;
            app.displayInfo.previousLimits{1,2} = app.displayInfo.axes.XLim;
            app.displayInfo.previousLimits{2,2} = app.displayInfo.axes.YLim;
            app.displayInfo.previousLimits{3,2} = app.displayInfo.axes.CLim;

            %Repeat figure creation/alteration process if needed for the colorbar
            if ~isfield(app.displayInfo,'colorbarProper')
                %If no colorbar has been made yet, generate one "normally"
                app.displayInfo.colorbarAxes = axes(app.ColorBarPanel);

                %All these settings just make the colorbar look nice
                colormap(app.displayInfo.colorbarAxes,"gray")
                app.displayInfo.colorbarAxes.Visible = 'off';
                app.displayInfo.colorbarProper = colorbar(app.displayInfo.colorbarAxes,'north');
                app.displayInfo.colorbarProper.Position = [.02 .3 .96 .7];
                app.displayInfo.colorbarProper.FontSize = 8;
                app.displayInfo.colorbarProper.TickLength = .02;
                app.displayInfo.colorbarProper.AxisLocation = 'in';

                %See the end of this function for a small rant regarding this
                %section of code
                %Edits position and font size of exponential label
                if app.displayInfo.colorbarAxes.CLim(2) >= 0
                    colorbarExponentialPosition = app.displayInfo.colorbarAxes.CLim(2) * .98;
                else
                    colorbarExponentialPosition = app.displayInfo.colorbarAxes.CLim(2) * 1.02;
                end
                app.displayInfo.colorbarProper.Ruler.SecondaryLabel.Position = [colorbarExponentialPosition .6];
                app.displayInfo.colorbarProper.Ruler.SecondaryLabel.FontSize = 9;
            end


            if app.displayInfo.colorbarProper.Limits ~= app.displayInfo.axes.CLim
                %If the limits of the colorbar don't already match the limits
                %of the color, change them to match. Just updates the limits on
                %them to prevent memory leak
                app.displayInfo.colorbarAxes.CLim = app.displayInfo.axes.CLim;
                app.displayInfo.colorbarProper.Limits = app.displayInfo.colorbarAxes.CLim;
            end

            %Puts what iteration is currently running. This is different than
            %the iteration of any single data point as that describes how many
            %times the data of that point has been taken. This describes how
            %many times the entire scan has repeated itself
            infoString{1} = sprintf('Iteration %d',app.ex.scan.iteration);

            app.OutputInfo.Value = infoString;


            %/begin rant
            %Some general background: Matlab figures, axes, and images as well
            %as similar visual items are all structures whose variables
            %indicate what MatLab should generate when run. This entire
            %function is premised on manipulation of those structures to
            %display whatever information is desired at the right time. These
            %structures have a whole host of properties and can only be
            %accessed directly by someone intentionally doing so.
            %Now, for whatever reason, the fine programmers at MatLab decided
            %that some of the variables within these structures should be
            %hidden. Let me repeat that: some of the variables within these
            %structures, that contain dozens of other variables mind, are
            %hidden from the user despite only relatively advanced users being
            %able to access this information at all. This might make sense if
            %the variables are critical to the function of the item and any
            %alteration would result in a cascading series of errors. However,
            %one of these hidden variables is the easily altered and not at
            %all critical position of the secondary label. The primary label
            %of something like a colorbar would be the numbers relating to
            %tick marks while the secondary label is for an exponential such
            %as x10^5 and is only present when needed. I do not know why such
            %a simple thing needs to be hidden from the user and not even
            %mentioned on official websites. I only wished to edit the
            %location of the exponential of the colorbar so that colorbar
            %could more fully utilize the space it is in and not cut out
            %important information. That was all. It is utterly baffling that
            %I needed to "find" the hidden variables to do such a simple task.
            %/rant
        end

        function ScanUpdate(app)
            app.RandomizescanCheckBox.Value = app.ex.randomizeScanPoints;

            if isfield(app.ex,'scan')
               tableType = app.ex.scan.type';
               tableStart = app.ex.scan.start';
               tableEnd = app.ex.scan.end';
               tablenSteps = app.ex.scan.nSteps';
               tableStepSize = app.ex.scan.stepSize';

               app.ScanningTable.Data = [tableType tableStart tableEnd tablenSteps tableStepSize];
            end
        end

        function StatusUpdate(app)
           switch app.scanStatus
              case 'running'
                 app.StatusLamp.Color = [0,1,0]; %green
              case 'reset'
                 app.StatusLamp.Color = [0,0,1]; %dark blue
              case 'aborted'
                 app.StatusLamp.Color = [1,0,0]; %red
              case 'completed'
                 app.StatusLamp.Color = [0,1,1]; %cyan
           end
        end

        function ScanIncrement(app,scanNumber)
            if ~app.ex.randomizeScanPoints
                scan = app.ex.scan;
                app.ex.scan.completed(scanNumber) = scan.completed(scanNumber)+1;
                app.ex.scan.storedDataSettings.completed = app.ex.scan.completed;
            else
                %scanNumber input becomes irrelevant as all steps need to be
                %changed. It instead becomes a vector containing every scan
                %whose number is not equivalent to the previous

            end
            switch scan.type(scanNumber)
                case {'Stage X','Stage Y','Stage Z'}
                    stageAxis = convertStringsToChars(scan.type(scanNumber));
                    stageAbsoluteMove(stageAxis(end),scan.start(scanNumber) + (scan.completed(scanNumber)-1)*scan.stepSize)
            end
        end

        function errorPresent = ErrorCheck(app)
            scan = app.ex.scan; %shorthand
            errorPresent = true;

            if ~app.stageControl.connected
                loadinstruments = uiconfirm(app.UIFigure,'Stage is not currently loaded. Would you like to load it or cancel measurement?',...
                    'Instruments unloaded','Icon','warning','Options',{'Load Stage','Cancel'});
                switch loadinstruments
                    case 'Load Stage'
                        stageInitialization
                        appUpdate(app)
                    case 'Cancel'
                        return
                end
            end

            if ~strcmp(app.scanStatus,'reset')
                loadinstruments = uiconfirm(app.UIFigure,'This will resume previous run adding on to old data. Proceed?',...
                    'Not Reset','Icon','warning','Options',{'Proceed','Cancel'});
                if strcmp(loadinstruments,'Cancel')
                    return
                end
            end

            %          if  ~strcmp(app.Warnings.Value{1},'No warnings present')
            %             disregard = uiconfirm(app.UIFigure,'Continue despite warnings?','Warnings present','Icon','warning');
            %             if strcmp(disregard,'Cancel')
            %                return
            %             end
            %          end

            if length(scan.start) == 1 && strcmp(scan.type,"Stage X") && scan.start == 1 && scan.end == 10 && scan.nSteps == 10 && scan.stepSize == 1
                errordlg('Scan has not been changed from default. Go to scan tab to change','Default Scan')
                return
            end

            boxDir = dir(strcat(app.BoxAddress.Value,'\Backlund lab shared\code\Function Library'));
            if isempty(boxDir)
                errordlg('Current box address invalid. Go to General tab to fix','Invalid box address')
                return
            end

            [~,uniqueIndices] = unique(scan.type);
            if numel(scan.type) ~= numel(uniqueIndices)
                errordlg('Multiple scans with identical types detected. Go to scan tab to change','Replicate Scans')
                return
            end

            scanStages = convertStringsToChars(scan.type);
            if isa(scanStages,'cell')
                scanStages = convertStringsToChars(scan.type);
                scanStages = cellfun(@(x) x(end),scanStages,'UniformOutput',false);
            else
                scanStages = convertStringsToChars(scan.type);
                scanStages = scanStages(end);
            end
            if any(ismember(scanStages,app.stageControl.sequence)) && app.StageAutoOptimization.Value
                proceedExperiment = uiconfirm(app.UIFigure,['Auto-optimization on while attempting stage scan of the same axis.' ...
                    'This is not recommended as the optimization process can affect the scan. Proceed anyways?'],...
                    'Incompatible Settings','Icon','warning','Options',{'Proceed','Cancel'});
                if strcmp(proceedExperiment,'Cancel')
                    return
                end
            end

            if  app.stageControl.ignoreWait && app.StageAutoOptimization.Value
                proceedExperiment = uiconfirm(app.UIFigure,['Auto-optimization on while ignore wait is also on.' ...
                    'This is not recommended as it skews the optimization process. Proceed anyways?'],...
                    'Incompatible Settings','Icon','warning','Options',{'Proceed','Cancel'});
                if strcmp(proceedExperiment,'Cancel')
                    return
                end
            end

            if strcmp(app.scanStatus,'running')
                errordlg('Cannot run experiment while optimization is running','Cannot Run Experiment')
                return
            end

            %% Warnings

            %If it reaches the end without triggering an error, set the output
            %to false
            errorPresent = false;
        end

        function newStructure = ModifyAllFields(~,oldStructure,fieldsToChange,fieldValues,address)
            %This function replaces holding all these settings in a variable
            %then changing them all at once to the structure. Instead it
            %changes each substructure one at a time
            %Honestly I don't think I would do it this way again if I had to
            %go back and redo it

            %It is necessary to input the fields to change not because matlab
            %cannot identify what fields are there, but because it needs to
            %match the field name to the new value to be input

            %An input of {'delete'} for the first cell in fieldValues input
            %will cause the function to delete the values of the address given

            newStructure = struct2cell(oldStructure);%Create new structure based of the old
            namesList = fieldnames(oldStructure);%Create list of field names in order

            for ii = 1:numel(fieldsToChange)
                %Find where the field name of the structure is the field name
                %that has been input
                cellNumber = find(strcmp(fieldsToChange(ii),namesList));

                if isempty(cellNumber)
                    error('Invalid field name. Name must be one of the fields of the structure input')
                end

                %Take the old structure, replace the value based on given
                %address, then add it back into the new structure
                newValue = newStructure{cellNumber};
                if strcmp(fieldValues{1},'delete')
                    newValue(address) = [];
                else
                    if address <= numel(newValue)
                        newValue(address+1:end+1) = newValue(address:end); %Shift all values by 1 leaving n to be replaced
                    end
                    newValue(address) = fieldValues{ii};
                end

                newStructure{cellNumber} = newValue;
            end

            %Converts cell back to structure using the names found from the
            %input structure
            newStructure = cell2struct(newStructure,namesList,1);

        end


        function newRootName = CheckFolder(~,oldRootName,folderName)
            %Updates what the new root name will be
            newRootName = strcat(oldRootName,'\',folderName);

            %Load the directory
            fileList = struct2cell(dir(oldRootName));
            fileList = fileList(1,:);%Only name row

            %Makes a new folder if one doesn't already exist
            fileExists = any(strcmp(folderName,fileList),"all");
            if ~fileExists
                mkdir(oldRootName,folderName)
            end

        end

        function DataSave(app,rootSaveName)
            %Should be a single variable not part of a structure when saved
            data = app.storedData;

            %Saves the data in a format readable by MatLab
                mSaveName = strcat(rootSaveName,'\', 'MatLab_Data.mat');
                save(mSaveName,"data")
                dataSettings = app.storedDataSettings;
                mSaveName = strcat(rootSaveName,'\', 'MatLab_Data_Settings.mat');
                save(mSaveName,"dataSettings")
                userNotes = app.SavedUserNotes.Value;
                mSaveName = strcat(rootSaveName,'\', 'MatLab_User_Notes.mat');
                save(mSaveName,"userNotes")

            %If saving as a tif is not selected, end the function here. I did
            %this instead of putting everything below in one massive if
            %statement because it makes code formatting nicer in my opinion
                uialert(app.UIFigure,'Data Saved!','Save Successful','Icon','success')

        end

        function SetupSave(app,rootSaveName)
            %Saves a number of settings that allow for easy loading back to
            %what one was doing before
            %REDO FOR OBJECT **********

        %     if isfield(master,'sCMOS')
        %         %Select substructure name
        %         instrumentName = 'sCMOS';
        %         %Select variables to copy within the above substructure
        %         toCopy = {'correctionLevel','framesPerTrigger','exposureTime','outputFrameStack',...
        %             'bounds','imageDimensions','useBounds','fuser'};
        % 
        %         %For each variable within the substructure selected above, copy it
        %         %over to savedSettings. Dynamic field naming is convenient :)
        %         for ii = 1:numel(toCopy)
        %             app.savedSettings.(instrumentName).(toCopy{ii}) = master.(instrumentName).(toCopy{ii});
        %         end
        %     end
        % 
        %     %Repeat for all other substructures
        %     %I could have looped this more, but I prefer to see the names
        %     %written out clearly like so
        % 
        %     if isfield(master,'stage')
        %         instrumentName = 'stage';
        %         toCopy = {'pauseTime','optimizationType','ignoreWait','tolerance',...
        %             'sequence','radius','steps'};
        %         for ii = 1:numel(toCopy)
        %             savedSettings.(instrumentName).(toCopy{ii}) = master.(instrumentName).(toCopy{ii});
        %         end
        %     end
        % 
        %     if isfield(master,'lasers')
        %         instrumentName = 'lasers';
        %         toCopy = {'wavelength','tolerance','waitForEquilibrium','offWhenCleaned'};
        %         for ii = 1:numel(toCopy)
        %             savedSettings.(instrumentName).(toCopy{ii}) = master.(instrumentName).(toCopy{ii});
        %         end
        %     end
        % 
        %     instrumentName = 'gui';
        %     toCopy = {'scan','autoStageOptimize','stageOptimizationInterval','loopVideo',...
        %         'xInc','yInc','zInc','videoPauseTime','videoScan','overrideCurrentStep',...
        %         'randomScan','saveName','saveType','saveAllFrames'};
        %     for ii = 1:numel(toCopy)
        %         savedSettings.(instrumentName).(toCopy{ii}) = master.(instrumentName).(toCopy{ii});
        %     end
        % 
        %     %Make all scan completion values equal 0 ******* Change this to
        %     %make completion not within scan?
        %     savedSettings.gui.scan.completed = zeros(numel(savedSettings.gui.scan.completed),1);
        % 
        %     %Do a similar process for the substructure display within gui
        %     instrumentName = 'display';
        %     toCopy = {'squareRatio','logarithmicContrast','dataSelection','useStaticColorLimits',...
        %         'staticColorLimits','limitsIfOverriden','overrideLimits'};
        %     for ii = 1:numel(toCopy)
        %         savedSettings.gui.(instrumentName).(toCopy{ii}) = app.general.(instrumentName).(toCopy{ii});
        %     end
        % 
        %     %Saves the setup and user notes
        %     if strcmp(app.general.saveType,'.m') || strcmp(app.general.saveType,'.m & .tif')
        %         mSaveName = strcat(rootSaveName,'\', 'Setup_Settings.mat');
        %         save(mSaveName,"savedSettings")
        %         userNotes = app.SavedUserNotes.Value;
        %         mSaveName = strcat(rootSaveName,'\', 'MatLab_User_Notes.mat');
        %         save(mSaveName,"userNotes")
        %     end
        % 
        %     uialert(app.UIFigure,'Setup Saved!','Save Successful','Icon','success')
        % 
        end

        % function app = loadInstrument(app,propertyName,propertyClassName)
        %     %Loads an instrument after checking its connection status
        % 
        %     %Checks for presence of corresponding config name
        %     if isempty(app.configNames) || ~isfield(app.configNames,propertyName) || isempty(app.configNames.(propertyName))
        %         error('No config file for %s loaded',propertyName)
        %     end
        % 
        %     %If given property already exists, check if it is the correct class. If it is, connect if not
        %     %already done so then end callback. If not, delete current property and continue
        %     if ~isempty(app.ex.(propertyName))
        %         if isa(app.ex.(propertyName),propertyClassName)
        %             if ~app.ex.(propertyName).connected
        %                 app.ex.(propertyName) = connect(app.ex.(propertyName),app.configNames.(propertyName));
        %             end
        %             return
        %         end
        %         app.ex.(propertyName) = [];
        %     end
        % 
        %     % %Creates instrument object using class name then attempts to connect using given config
        %     % app.ex.(propertyName) = feval(propertyClassName);
        %     % app.ex.(propertyName) = connect(app.ex.(propertyName),app.configNames.(propertyName));
        % 
        %     AppUpdate(app)
        % end

        function instrumentOn = checkInstrumentStatus(app,propertyName)

            if isempty(app.ex.(propertyName)) || ~app.ex.(propertyName).connected
                instrumentOn = false;
                fprintf('%s not connected\n',propertyName)
            else
                instrumentOn = true;
            end
        end

    end

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFunction(app)
            %Creates experiment object
            
            app.StageConfig.Value = 'PI_stage';
            app.PulseBlasterConfig.Value = 'pulse_blaster_default';
            app.DAQConfig.Value = 'daq_6361';
            app.SRSRFConfig.Value = 'SRS_RF';
            app.WindfreakRFConfig.Value = 'windfreak_RF';
            %app.SproutLaserConfig.Value = 'weneedaconfig'

            app.SaveFolderName.Value = [];
            app.BoxAddress.Value = [];
            app.UserName.Value = 'Common';
            app.SavedUserNotes.Value = '';
            app.DataSetupsSwitch.Value = 'Data';
            app.stageAddress.Value = 1;
            app.ex.scan.completed = 0;
            app.ex.scan.nSteps = 1;
            app.ex.scan.type = [];
            app.ex.scan.start = [];
            app.ex.scan.end = [];
            app.ex.scan.stepSize = [];
            app.storedDataSettings.nScans = 0;
            app.storedDataSettings.scan = app.ex.scan;
            app.storedDataSettings.scan.stepToView = [];
            app.ex.randomizeScanPoints = 0;
            app.displayInfo.squareRatio = 0;
            app.displayInfo.dataSelection = 'Average';
            app.displayInfo.limitsIfOverriden = {[],[1,100];[],[1,100];[],[1,100]};
            app.displayInfo.overrideLimits = {[],[];[],[];[],[]};
            app.displayInfo.staticColorLimits = [0 1];
            app.displayInfo.useStaticColorLimits = 0;

            %Loads any settings that were saved when closing the app
            %previously in the same MatLab session
            if ~isempty(app.savedSettings)
                app.displayInfo = app.savedSettings.displaySettings;
                app.stageControl = app.savedSettings.stageControl;
                app.laser488 = app.savedSettings.laser488;
                app.laser561 = app.savedSettings.laser561;
                app.laser640 = app.savedSettings.laser640;
                app.savedSettings = [];
            end

            app.UserName.Items = app.userList;
            
            setOptimizationInfo(app) %creates optimizationInfo in app.ex, should be deleted eventually
            AppUpdate(app)

      end

      % Button pushed function: StartButton
      function StartButtonPushed(app, event)
            %If there are any errors, stop this function
            % if ErrorCheck(app)
            %     return
            % end
            % 
            % %If a new scan is ready, create empty data matrix with the correct
            % %dimensions and set the completed scan to 0 for all scan
            % %dimensions
            % if app.general.reset
            %     %Removes old saved data if there is any
            %     if ~isempty(app.storedData)
            %         app.storedData = [];
            %     end
            %     if isscalar(app.ex.scan.nSteps)
            %         app.storedData = cell(app.ex.scan.nSteps,1);
            %     else
            %         app.storedData = cell(app.ex.scan.nSteps);
            %     end
            %     app.ex.scan.completed = zeros(size(app.ex.scan.nSteps));
            %     app.ex.scan.iteration = 1;
            %     app.general.reset = false;%Do not reset again until the reset button is pressed
            %     %If random scan is on, create a permutation list for this
            %     %iteration
            %     if app.ex.randomizeScanPoints
            %         app.ex.scan.permutation = randperm(prod(app.ex.scan.nSteps));
            %         app.general.currentStepRandomScan = 0;
            %     end
            %     app.general.dataTakenWithTwoBounds = app.hammCam.useBounds(2);
            % 
            %     app.storedDataSettings.useBounds = app.hammCam.useBounds;
            %     app.storedDataSettings.frameStacks = app.hammCam.outputFrameStack;
            %     app.storedDataSettings.scan = app.general.scan;
            %     app.storedDataSettings.nScans = numel(app.ex.scan.start);
            % 
            % end
            % 
            % app.general.scanRunning = true;
            % StatusUpdate(app)
            % 
            % if ~app.hammCam.useBounds(2)
            %     %Only 1 set of bounds. First set of bounds only regulates the
            %     %size of the output but the second set regulates both the size
            %     %and whether a second image is produced at all
            %     if app.hammCam.outputFrameStack
            %         %Outputs frame stack
            %         app.general.nOutputs = 2;
            %     else
            %         app.general.nOuputs = 1;
            %     end
            % else
            %     %2 sets of bounds
            %     if app.hammCam.outputFrameStack(2)
            %         %Outputs frame stack of second set of bounds
            %         app.general.nOutputs = 4;
            %     else
            %         app.general.nOuputs = 3;
            %     end
            % end
            % 
            % 
            % while true %Iteration Loop
            % 
            %     %Add all of previous iteration to the sum and set current
            %     %iteration to 0s
            % 
            % 
            % 
            % 
            %     while app.general.scanRunning %Scanning Loop
            % 
            % 
            % 
            %         if ~app.ex.randomizeScanPoints
            % 
            %             %Begin by checking if any scans haven't moved to their starting
            %             %location and if they haven't, move them there
            %             unstarted = find(app.ex.scan.completed == 0,1);
            %             if ~isempty(unstarted)
            %                 for ii = 1:numel(unstarted)
            %                     ScanIncrement(app,unstarted(ii))
            %                 end
            %                 TakeData(app)
            %                 DisplayUpdate(app)
            %             end
            % 
            %             %Incremental loop to change instrument setting dependent on
            %             %where the scan currently is
            %             ii = numel(app.ex.scan.nSteps); %Counting variable that begins at the last scan
            %             while true
            % 
            %                 if app.ex.scan.completed == app.ex.scan.nSteps %All scans are done
            %                     %Completely stop running and set preparedness for next scan
            %                     %to ready
            %                     takeDataBoolean = false;
            %                     break %Incremental loop
            % 
            %                 elseif app.ex.scan.completed(ii) == app.ex.scan.nSteps(ii) %Check if current scan is done
            %                     %Reset current scan and go to next scan
            %                     %e.g. if scan 3 has completed the number of steps it
            %                     %does in total, set scan 3 back to its first step then
            %                     %look at scan 2
            %                     app.ex.scan.completed(ii) = 0;
            %                     ScanIncrement(app,ii)
            %                     ii = ii-1;
            % 
            %                 else %Current scan isn't done
            %                     %Increment current scan
            %                     ScanIncrement(app,ii)
            %                     takeDataBoolean = true;
            %                     break %Incremental loop
            %                 end
            % 
            %             end
            % 
            %             if takeDataBoolean
            %                 %Take a new data point and add it to the data cell matrix
            %                 TakeData(app)
            %             else
            %                 break %Scanning loop
            %             end
            % 
            %         else %Randomized scan
            %             %Uses linear indexing of a permuted list of scans to
            %             %determine
            % 
            %             if app.general.currentStepRandomScan ~= prod(app.ex.scan.nSteps) %not done
            %                 app.general.currentStepRandomScan = app.general.currentStepRandomScan + 1;
            %                 %Convert linear index to row/col, use that to
            %                 %determine what step to move to then take data
            % 
            %             end
            %         end
            % 
            %         app.storedDataSettings.scan.completed = app.ex.scan.completed;
            % 
            %         %Update the output page - will only change if needed
            %         DisplayUpdate(app)
            % 
            %     end
            % 
            %     if app.general.scanRunning %Not aborted
            %         app.ex.scan.completed = zeros(size(app.ex.scan.nSteps));
            %         if app.ex.randomizeScanPoints
            %             app.ex.scan.permutation = randperm(prod(app.ex.scan.nSteps));
            %             app.general.currentStepRandomScan = 0;
            %         end
            %     else
            %         return
            %     end
            % 
            %     %If all iterations are done
            %     if app.ex.scan.iteration == app.general.maxIteration && app.general.scanRunning
            %         %Set app back to starting condition and break iteration loop
            %         app.general.reset = true;
            %         app.general.scanRunning = false;
            %         StatusUpdate(app)
            %         break %Iteration loop
            %     end
            % 
            % end

      end

      % Button pushed function: AbortButton
      function AbortButtonPushed(app, event)
            app.scanStatus = 'aborted';
            app.videoRunning = false;
            app.StatusLamp.Color = [1,0,0];
            AppUpdate(app)
      end

      % Button pushed function: StageUnload
      function StageUnloadButtonPushed(app, event)
            %Unloads stage by running CleanUp function then updates the app
            disconnect(app.ex.PIstage);
            AppUpdate(app)
      end

      % Button pushed function: RefreshAppButton
      function RefreshAppButtonPushed(app, event)
            %Refreshes all panels of the app
            AppUpdate(app)
      end

      % Callback function
      function NotificationsValueChanged(app, event)
            %Turns command window notifications on or off
            app.hammCam.notifications = true;
            app.stageControl.notifications = true;
      end

      % Button pushed function: DeleteOptimizationStep
      function DeleteOptimizationStepPushed(app, event)
            address = app.stageAddress.Value;
            currentLength = length(app.stageControl.sequence);

            if address > currentLength
                errordlg('Selected address does not exist','Invalid Address')
            elseif address == currentLength && address == 1
                errordlg('Cannot delete the last step','Cannot Delete')
            else
                fieldsToChange = {'sequence','radius','steps'};
                fieldValues = {'delete'};
                app.stageControl = AddToAllFields(app,app.stageControl,fieldsToChange,fieldValues,address);
            end
            PositionUpdate(app)
      end

      % Cell edit callback: ScanningTable
      function ScanningTableCellEdit(app, event)
            rowNum = event.Indices(1);
            newData = event.NewData;
            switch event.Indices(2)
                case 1 %type
                    switch lower(newData)
                        case {'stage x','x'}
                            app.ex.scan(rowNum).instrument = 'stage';
                            app.ex.scan(rowNum).parameter = 'x';
                        case {'stage y','y'}
                            app.ex.scan(rowNum).instrument = 'stage';
                            app.ex.scan(rowNum).parameter = 'y';
                        case {'stage z','z'}
                            app.ex.scan(rowNum).instrument = 'stage';
                            app.ex.scan(rowNum).parameter = 'z';
                        case {'rf','rf frequency','frequency','f','freq','odmr'}
                            app.ex.scan(rowNum).instrument = 'RF_generator';
                            app.ex.scan(rowNum).parameter = 'frequency';

                    end

                case {2,3}
                    %start or end
                    %TO DO: ensure scans where start is less than end work
                    %properly
                    if event.Indices(2) == 2
                        app.ex.scan.start(rowNum) = str2double(newData);
                    else
                        app.ex.scan.end(rowNum) = str2double(newData);
                    end

                    if strcmpi(app.DefaultScanChange.Value,'step size')
                        %Change step size while keeping number of steps constant
                        app.ex.scan.stepSize(rowNum) = (app.ex.scan.end(rowNum) - app.ex.scan.start(rowNum)) ./ (app.ex.scan.nSteps(rowNum)-1);
                    else
                        %Change number of steps while keeping step size as close
                        %as possible to the original
                        stepChecker = (app.ex.scan.end(rowNum) - app.ex.scan.start(rowNum)) ./ app.ex.scan.stepSize(rowNum) + 1;

                        if stepChecker <= 0
                            errordlg('Number of steps became negative, changing step size instead')
                            app.ex.scan.stepSize(rowNum) = (app.ex.scan.end(rowNum) - app.ex.scan.start(rowNum)) ./ (app.ex.scan.nSteps(rowNum)-1);
                        elseif stepChecker == floor(stepChecker)
                            app.ex.scan.nSteps(rowNum) = stepChecker;
                        else
                            stepChecker = round(stepChecker);
                            app.ex.scan.nSteps(rowNum) = stepChecker;
                            app.ex.scan.stepSize(rowNum) = (app.ex.scan.end(rowNum) - app.ex.scan.start(rowNum)) ./ (app.ex.scan.nSteps(rowNum)-1);
                            changeWarning = sprintf('Step size changed to %.4g to allow for whole number of steps',app.ex.scan.stepSize(rowNum));
                            warndlg(changeWarning,'Altered Step Size')
                        end

                    end

                case 4 % # of steps
                    if str2double(newData) == floor(str2double(newData)) && str2double(newData) > 0
                        app.ex.scan.nSteps(rowNum) = str2double(newData);
                    else
                        errordlg('Number of steps must be a whole number','Invalid # of Steps')
                    end
                    app.ex.scan.stepSize(rowNum) = (app.ex.scan.end(rowNum) - app.ex.scan.start(rowNum)) ./ (app.ex.scan.nSteps(rowNum)-1);
                case 5 %step size
                    stepChecker = (app.ex.scan.end(rowNum) - app.ex.scan.start(rowNum)) ./ str2double(newData) + 1;
                    if  stepChecker <= 0
                        errordlg('Number of steps must be a whole number, step size must have corresponding sign','Invalid Step Size')
                    elseif stepChecker == floor(stepChecker)
                        app.ex.scan.nSteps(rowNum) = stepChecker;
                        app.ex.scan.stepSize(rowNum) = str2double(newData);
                    else
                        stepChecker = round(stepChecker);
                        app.ex.scan.nSteps(rowNum) = stepChecker;
                        app.ex.scan.stepSize(rowNum) = (app.ex.scan.end(rowNum) - app.ex.scan.start(rowNum)) ./ (app.ex.scan.nSteps(rowNum)-1);
                        changeWarning = sprintf('Step size changed to %.4g to allow for whole number of steps',app.ex.scan.stepSize(rowNum));
                        warndlg(changeWarning,'Altered Step Size')
                    end
                case 6 %pulse addresses

            end

            ScanUpdate(app)
            OutputSettingsUpdate(app)

      end

      % Button pushed function: DeleteScanStep
      function DeleteScanStepPushed(app, event)
            address = app.ScanAddress.Value;
            currentLength = length(app.ex.scan.type);

            if address > currentLength
                errordlg('Selected address does not exist','Invalid Address')
            elseif address == currentLength && address == 1
                errordlg('Cannot delete the last step','Cannot Delete')
            else
                fieldsToChange = {'type','start','end','nSteps','stepSize','completed','stepToView'};
                fieldValues = {'delete'};
                app.ex.scan = ModifyAllFields(app,app.ex.scan,fieldsToChange,fieldValues,address);
            end

            ScanUpdate(app)
            OutputSettingsUpdate(app) %Needed for step to view selection and the like
      end

      % Button pushed function: ResetButton
      function ResetButtonPushed(app, event)
            if strcmp(app.scanStatus,'running')
                errordlg('Abort the what is currently running before attempting to reset','Abort First')
            else
                %Set ready status to true,
                app.scanStatus = 'reset';
                app.ex.scan.completed = zeros(1,numel(app.ex.scan.start));
                app.storedDataSettings.scan.completed = app.ex.scan.completed;
            end
            StatusUpdate(app)


      end

      % Callback function
      function OutputrefreshButtonPushed(app, event)
            DisplayUpdate(app)
      end

      % Cell edit callback: ScanStepTable
      function ScanStepTableCellEdit(app, event)
            newData = str2double(event.NewData);
            if isnan(newData)
                OutputSettingsUpdate(app)
                errordlg('Step to view must be a number','Invalid Step')
                return
            end
            if newData <= app.ex.scan.nSteps(event.Indices(1)) && newData > 0 && floor(newData) == newData
                app.ex.scan.stepToView(event.Indices(1)) = newData;
                app.storedDataSettings.scan.stepToView(event.Indices(1)) = newData;
            else
                errordlg('Step to view must be an integer between 1 and total number of steps inclusive','Invalid Step')
            end
            OutputSettingsUpdate(app)
      end

      % Value changed function: RandomizescanCheckBox
      function RandomizescanCheckBoxValueChanged(app, event)
            app.ex.randomizeScanPoints = app.RandomizescanCheckBox.Value;
            ScanUpdate(app)
            OutputSettingsUpdate(app)
      end

      % Callback function
      function LogarithmicContrastCheckBoxValueChanged(app, event)
            app.displayInfo.logarithmicContrast = app.LogarithmicContrastCheckBox.Value;
            OutputSettingsUpdate(app)
      end

      % Value changed function: ForcesquareaspectratioforimagesCheckBox
      function ForcesquareaspectratioforimagesCheckBoxValueChanged(app, event)
            app.displayInfo.squareRatio = app.ForcesquareaspectratioforimagesCheckBox.Value;
            OutputSettingsUpdate(app)
      end

      % Value changed function: BoxAddress
      function BoxAddressValueChanged(app, event)
            app.BoxAddress.Value = app.BoxAddress.Value;
            SaveDataUpdate(app)
      end

      % Button pushed function: SaveButton
      function SaveButtonPushed(app, event)

            if strcmp(app.DataSetupsSwitch.Value,'Data') && ~isempty(app.storedData)
                errordlg('No data to save','Save Unsuccessful')
                return
            end

            rootSaveName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',app.UserName.Value,['\Experiment Controller SM ',app.DataSetupsSwitch.Value]);

            %Checks if the directory is valid. Even if it is empty, there will
            %be 2 "fake" files
            setupDirectory = struct2cell(dir(rootSaveName));
            if isempty(setupDirectory)
                errordlg('Current box address invalid. Go to Connection tab to fix','Invalid box address')
                return
            end

            %Checks if the given folders are present and makes them if they
            %aren't. Data folders are first sorted by date and then by name
            %while setup folders are the reverse
            if strcmp(app.DataSetupsSwitch.Value,'Data')
                rootSaveName = CheckFolder(app,rootSaveName,string(datetime('today','Format','yyyy-MM-dd')));
                rootSaveName = CheckFolder(app,rootSaveName,app.SaveFolderName.Value);
            else
                rootSaveName = CheckFolder(app,rootSaveName,app.SaveFolderName.Value);

                versionDirectory = struct2cell(dir(rootSaveName));
                versionDirectory = versionDirectory(1,:);%Only name row
                if numel(versionDirectory) == 2
                    versionNumber = 1;
                else
                    versionDirectory = versionDirectory(:,3:end);
                    versionDirectory = cellfun(@(a)a(2:end-13),versionDirectory,'UniformOutput',false);
                    versionNumber = max(str2double(versionDirectory))+1;
                end
                newName = sprintf('v%d - %s',versionNumber,string(datetime('today','Format','yyyy-MM-dd')));
                rootSaveName = CheckFolder(app,rootSaveName,newName);
            end

            %At this point, the root name is the exact folder the files will
            %be saved in
            %Two functions are done here to make the code more legible

            if strcmp(app.DataSetupsSwitch.Value,'Data')
                DataSave(app,rootSaveName)
            else
                SetupSave(app,rootSaveName)
            end

            SaveDataUpdate(app)


      end

      % Cell edit callback: PlotLimits
      function PlotLimitsCellEdit(app, event)
            if event.Indices(2) == 4
                %If input character array is true/false (or some variant), set the
                %corresponding row of the overrideLimits cell to a logical true
                %or false
                if any(strcmp(event.NewData,{'true','True','TRUE','yes','Yes','YES','on','On','ON','t','T','1'}))
                    app.displayInfo.overrideLimits{event.Indices(1),2} = true;
                elseif any(strcmp(event.NewData,{'false','False','FALSE','no','No','NO','off','Off','OFF','f','F','0'}))
                    app.displayInfo.overrideLimits{event.Indices(1),2} = false;
                else
                    errordlg('Invalid override limit choise. Must be "true" or "false"','Invalid Override Choice')
                end
            else
                newData = str2double(event.NewData);
                if event.Indices(2)-1 == 1
                    %Minimum
                    if newData >= app.displayInfo.limitsIfOverriden{event.Indices(1),2}(2)
                        %New minimum is greater than or equal to the maximum for
                        %that axis
                        errordlg('Invalid minimum. Must be less than maximum.','Invalid Minimum')
                        OutputSettingsUpdate(app)
                        return
                    end
                else
                    %Maximum
                    if newData <= app.displayInfo.limitsIfOverriden{event.Indices(1),2}(1)
                        %New maximum is less than or equal to the minimum for
                        %that axis
                        errordlg('Invalid maximum. Must be greater than minimum.','Invalid Maximum')
                        OutputSettingsUpdate(app)
                        return
                    end
                end

                if event.Indices(1) == 3
                    if newData > 100 || newData < 0
                        %Color percentile must be between 0 and 100 because that
                        %is how percentiles work
                        errordlg('Invalid color percentile. Must be between 0 and 100.','Invalid Percentile')
                        OutputSettingsUpdate(app)
                        return
                    end
                end

                %Set the relevant row of the display limits cell to the new
                %value with the column indicating minimum or maximum within the
                %limits matrix
                app.displayInfo.limitsIfOverriden{event.Indices(1),2}(event.Indices(2)-1) = newData;
            end

            %Lets app know that the limits have been changed
            app.displayInfo.alteredLimits = true;

            OutputSettingsUpdate(app)
      end

      % Callback function
      function ZoomIn2buttonsquareButtonPushed(app, event)
            if ~isfield(app.displayInfo,'axes')
                errordlg('No image is present to zoom in on','Invalid Zoom')
                return
            end

            %The user must select 2 points within the axes and
            regionOfInterest = drawrectangle(app.displayInfo.axes);

            try

                %changes x and y axis limits as chosen by the above rectangle
                app.displayInfo.limitsIfOverriden{1,2} = ...
                    [round(regionOfInterest.Vertices(1,1)) round(regionOfInterest.Vertices(3,1))];
                app.displayInfo.limitsIfOverriden{2,2} = ...
                    [round(regionOfInterest.Vertices(1,2)) round(regionOfInterest.Vertices(2,2))];

                %Lets app know that the limits have been changed
                app.displayInfo.alteredLimits = true;

                app.displayInfo.overrideLimits{1,2} = true;
                app.displayInfo.overrideLimits{2,2} = true;

                delete(regionOfInterest)
            catch
                warndlg('Zoom did not complete due to invalid bounds','Zoom Aborted')
            end

            OutputSettingsUpdate(app)
      end

      % Callback function
      function ZoomOutButtonPushed(app, event)
            %WARNING: there is a known bug where MatLab does not properly zoom
            %out when the limits are changed back to their defaults. I have no
            %idea why this happens as the solution is literally to enter the
            %same values in manually. Scroll wheel out still works, so this
            %isn't debilitating, merely annoying
            if ~isfield(app.displayInfo,'previousData')
                %If no data has been displayed, zooming out makes no sense
                warndlg('Cannot zoom out when no data is displayed','Failed Zoom Out')
                return
            end

            app.displayInfo.overrideLimits{1,2} = false;
            app.displayInfo.overrideLimits{2,2} = false;

            %Lets app know that the limits have been changed
            app.displayInfo.alteredLimits = true;

            OutputSettingsUpdate(app)

      end

      % Value changed function: DropDown
      function DropDownValueChanged(app, event)
            app.displayInfo.dataSelection = app.DropDown.Value;
            OutputSettingsUpdate(app)
      end

      % Cell edit callback: StaticColorLimits
      function StaticColorLimitsCellEdit(app, event)
            newData = str2double(event.NewData);%Can fail
            if isnan(newData)
                errordlg('Invalid input. Must be a number','Invalid Input')
                OutputSettingsUpdate(app)
                return
            end
            if event.Indices(1) == 1 && newData >= app.displayInfo.staticColorLimits(2)
                errordlg('Invalid input. Minimum must be less than maximum','Invalid Input')
                OutputSettingsUpdate(app)
                return
            elseif event.Indices(1) == 2 && newData <= app.displayInfo.staticColorLimits(1)
                errordlg('Invalid input. Maximum must be more than minimum','Invalid Input')
                OutputSettingsUpdate(app)
                return
            end
            app.displayInfo.staticColorLimits(event.Indices(1)) = newData;

            OutputSettingsUpdate(app)
      end

      % Value changed function: UseStaticColorLimits
      function UseStaticColorLimitsValueChanged(app, event)
            app.displayInfo.useStaticColorLimits = app.UseStaticColorLimits.Value;
            app.displayInfo.overrideLimits{3,2} = app.displayInfo.useStaticColorLimits;
            app.displayInfo.alteredLimits = true;
            OutputSettingsUpdate(app)
      end

      % Button pushed function: LoadButton
      function LoadButtonPushed(app, event)
            %REDO THIS FUNCTION FOR NEW OBJECTS*****

            %When loading data, overwrite necessary output tab settings (i.e. scan type)

            rootLoadName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',app.UserName.Value);
            if strcmp(app.DataSetupsSwitch.Value,'Data')
                rootLoadName = strcat(rootLoadName,'\Experiment Controller SM Data');
                %Makes the root now include the date then name
                rootLoadName = strcat(rootLoadName,'\', app.LoadDates.Value,'\',app.LoadNames.Value);

                loadName = strcat(rootLoadName,'\', 'MatLab_Data.mat');
                load(loadName,"data")
                loadName = strcat(rootLoadName,'\', 'MatLab_Data_Settings.mat');
                load(loadName,"dataSettings")

                app.storedData = data;
                app.storedDataSettings  = dataSettings;

                loadName = strcat(rootLoadName,'\', 'MatLab_User_Notes.mat');
                load(loadName,"userNotes")
                app.SavedUserNotes.Value = userNotes;

                SaveDataUpdate(app)
                DisplayUpdate(app)

                uialert(app.UIFigure,'Data Loaded!','Save Successful','Icon','success')

            else
                rootLoadName = strcat(rootLoadName,'\Experiment Controller SM Setups');
                %Makes the root now include the name then date
                rootLoadName = strcat(rootLoadName,'\', app.LoadNames.Value,'\',app.LoadDates.Value);

                loadName = strcat(rootLoadName,'\', 'Setup_Settings.mat');
                load(loadName,"savedSettings")

                savedNames = fieldnames(savedSettings);

                %For each field name present within the loaded settings,
                %replace the current setting with the loaded one
                for ii = 1:numel(savedNames)
                    subSavedNames = fieldnames(savedSettings.(savedNames{ii}));
                    for jj = 1:numel(subSavedNames)
                        master.(savedNames{ii}).(subSavedNames{jj}) = ...
                            savedSettings.(savedNames{ii}).(subSavedNames{jj});
                    end
                end

                loadName = strcat(rootLoadName,'\', 'MatLab_User_Notes.mat');
                load(loadName,"userNotes")
                app.SavedUserNotes.Value = userNotes;

                AppUpdate(app)

                uialert(app.UIFigure,'Setup Loaded!','Save Successful','Icon','success')

            end


      end

      % Button pushed function: ScreenshotButton
      function ScreenshotButtonPushed(app, event)
            %Saves the screenshot to current date folder on box for the
            %individual

            %This is the name for the data folder for this app for this person
            rootSaveName = strcat(app.BoxAddress.Value,'\Backlund lab shared\data\',app.UserName.Value,'\Experiment Controller SM Data');

            %Load the directory
            fileList = struct2cell(dir(rootSaveName));

            %If no folders appear, the box address is almost certainly the
            %issue
            if isempty(fileList)
                errordlg('Invalid box address. Change on the Save/Load tab','Invalid Box Address')
                return
            end

            %Finds current date
            currentDate = string(datetime('today','Format','yyyy-MM-dd'));

            %Check to see if the folder in question exists, then, if not, make
            %one. Update root save name to include new folder
            fileExists = any(strcmp(currentDate,fileList),"all");
            if ~fileExists
                mkdir(rootSaveName,currentDate)
            end
            rootSaveName = strcat(rootSaveName,'\',currentDate);

            %Check to see if the folder in question exists, then, if not, make
            %one. Update root save name to include new folder
            fileList = struct2cell(dir(rootSaveName));
            fileExists = any(strcmp('Screenshots',fileList),"all");
            if ~fileExists
                mkdir(rootSaveName,'Screenshots')
            end
            rootSaveName = strcat(rootSaveName,'\','Screenshots');

            %Check to see if the file in question already exists
            fileList = struct2cell(dir(rootSaveName));
            fileList = fileList(1,:); %only name row
            fileExists = contains(fileList,app.SaveFolderName.Value);

            if ~any(fileExists,'all')
                %No file with this name exists, so it is saved just with the
                %base name
                fullSaveName = strcat(rootSaveName,'\',app.SaveFolderName.Value);
            elseif sum(fileExists,"all") == 1
                %Only 1 file with the name exists so the next one is saved with
                %a 2
                fullSaveName = strcat(rootSaveName,'\',app.SaveFolderName.Value,' 2');
            else
                versionNumber = cellfun(@(a)str2double(a(numel(app.SaveFolderName.Value)+2:end-4)),fileList,'UniformOutput',false);
                versionNumber = max(cell2mat(versionNumber(cellfun(@(a)~isnan(a),versionNumber))))+1;
                fullSaveName = sprintf('%s%s %d',strcat(rootSaveName,'\'),app.SaveFolderName.Value,versionNumber);
            end

            %Takes a screenshot of the app's current screen
            exportapp(app.UIFigure,strcat(fullSaveName,'MatLab_App_Screenshot.png'))

            %Creates temporary figure and axes that match the display axes so
            %that an image of them can be saved
            tempFigure = figure('Visible','off');
            tempAxes = copyobj(app.displayInfo.axes,tempFigure);
            %Changes appearance of figure to make a better picture
            set(tempAxes, 'Units', 'Normalized'); % First change to normalized units.
            set(tempAxes, 'OuterPosition', [0.05, 0.05, .9, .9]); % [xLeft, yBottom, width, height]
            %Saves the figure then closes it
            saveas(tempAxes,strcat(fullSaveName,'MatLab_Axes_Screenshot','png'))
            close(tempFigure)

      end

      % Button pushed function: CollectdatacontinuouslyButton
      function CollectdatacontinuouslyButtonPushed(app, event)
            %Sets video running to true. When aborted, this will be set to
            %false and the loop below will end
            app.videoRunning = true;
            StatusUpdate(app)


            firstLoop = true;
            while app.videoRunning
                %End the loop if the figure has been closed
                im = sCMOSImage;
                if ~isvalid(continuousData)
                    app.videoRunning = false;
                    break
                elseif firstLoop
                    %Creates a separate figure outside of the app then populates it
                    %with axes, an initial image set to grayscale, and a colorbar
                    continuousData = figure;
                    cdAxes = axes(continuousData); %#ok<LAXES>
                    im = sCMOSImage;
                    cdIm = imagesc(cdAxes,im);
                    colormap(app.displayInfo.axes,"gray")
                    colorbar
                    firstLoop = false;
                else
                    %Just updates the data of the image
                    cdIm.CData = im;
                end

                %See DisplayUpdate for comments regarding this section
                if app.displayInfo.overrideLimits{3,2}
                    if ~app.displayInfo.useStaticColorLimits
                        lowLimit = prctile(displayData,app.displayInfo.limitsIfOverriden{3,2}(1),"all");
                        highLimit = prctile(displayData,app.displayInfo.limitsIfOverriden{3,2}(2),"all");
                    else
                        lowLimit = app.displayInfo.staticColorLimits(1);
                        highLimit = app.displayInfo.staticColorLimits(2);
                    end
                else
                    lowLimit = min(displayData,[],"all");
                    highLimit = max(displayData,[],"all");
                end
                if lowLimit > cdAxes.CLim(2)
                    cdAxes.CLim(2) = lowLimit + .01;
                end
                cdAxes.CLim(1) = lowLimit;
                cdAxes.CLim(2) = highLimit;
            end

            %If the figure is still valid, close it
            if isvalid(continuousData)
                close(continuousData)
            end

            StatusUpdate(app)

      end

      % Callback function
      function TakesingleimageButtonPushed(app, event)
            %Creates a separate figure outside of the app then populates it
            %with axes, an initial image set to grayscale, and a colorbar
            continuousData = figure;
            cdAxes = axes(continuousData);
            im = takeImage(app.hammCam);
            if app.displayInfo.forceConcatenation
                %Two images

                if app.displayInfo.horizontalConcatenation
                    %Find if vertical sides are identical
                    %If not, add rows of 0s to both top and bottom of the
                    %shorter image
                    firstSide = size(im{1},1);
                    secondSide = size(im{2},1);
                    if firstSide < secondSide
                        sideDiff = secondSide - firstSide;
                        rowsToAdd = floor(sideDiff/2);
                        im{1}(rowsToAdd+1:end+rowsToAdd,:) = im{1};
                        im{1}(1:rowsToAdd,:) = zeros(rowsToAdd,size(im{1},2));
                        im{1}(end+1:end+rowsToAdd,:) = zeros(rowsToAdd,size(im{1},2));
                        if mod(sideDiff,2)
                            im{1}(end+1,:) = zeros(1,size(im{1},2));
                        end
                        finalIm = [im{1} im{2}];
                    elseif firstSide > secondSide
                        sideDiff = abs(secondSide - firstSide);
                        rowsToAdd = floor(sideDiff/2);
                        im{2}(rowsToAdd+1:end+rowsToAdd,:) = im{2};
                        im{2}(1:rowsToAdd,:) = zeros(rowsToAdd,size(im{2},2));
                        im{2}(end+1:end+rowsToAdd,:) = zeros(rowsToAdd,size(im{2},2));
                        if mod(sideDiff,2)
                            im{2}(end+1,:) = zeros(1,size(im{2},2));
                        end
                        finalIm = [im{1} im{2}];
                    end
                else
                    %Find if horizontal sides are identical
                    %If not, add columns of 0s to both right and left of the
                    %skinnier image
                    firstSide = size(im{1},2);
                    secondSide = size(im{2},2);
                    if firstSide < secondSide
                        sideDiff = secondSide - firstSide;
                        colsToAdd = floor(sideDiff/2);
                        im{1}(:,colsToAdd+1:end+colsToAdd) = im{1};
                        im{1}(:,1:colsToAdd) = zeros(size(im{1},1),colsToAdd);
                        im{1}(:,end+1:end+colsToAdd) = zeros(size(im{1},2),colsToAdd);
                        if mod(sideDiff,2)
                            im{1}(:,end+1) = zeros(size(im{1},2),1);
                        end
                        finalIm = [im{1};im{2}];
                    elseif firstSide > secondSide
                        sideDiff = abs(secondSide - firstSide);
                        colsToAdd = floor(sideDiff/2);
                        im{2}(:,colsToAdd+1:end+colsToAdd) = im{2};
                        im{2}(:,1:colsToAdd) = zeros(size(im{2},1),colsToAdd);
                        im{2}(:,end+1:end+colsToAdd) = zeros(size(im{2},2),colsToAdd);
                        if mod(sideDiff,2)
                            im{2}(:,end+1) = zeros(size(im{2},2),1);
                        end
                        finalIm = [im{1};im{2}];
                    end
                end
            else
                %Single image only
                finalIm = im{1};
            end
            imagesc(cdAxes,finalIm)
            colormap(app.displayInfo.axes,"gray")
            colorbar
      end

      % Callback function
      function forceConcatenationValueChanged(app, event)
            app.displayInfo.forceConcatenation = app.forceConcatenation.Value;
            OutputSettingsUpdate(app)
      end

      % Callback function
      function SwitchValueChanged(app, event)
            app.displayInfo.horizontalConcatenation = app.Switch.Value;
            OutputSettingsUpdate(app)
      end

      % Button pushed function: CopyappobjecttoworkspaceButton
      function CopyappobjecttoworkspaceButtonPushed(app, event)
            %Makes a copy of the current app in the workspace. Mainly for
            %debugging
            assignin("base","app",app)
      end

      % Button pushed function: StageLoad
      function StageLoadButtonPushed(app, event)
            app.ex = loadInstruments(app.ex,'PIstage',app.StageConfig.Value,false);
            AppUpdate(app)
      end

      % Button pushed function: PulseBlasterLoad
      function PulseBlasterLoadButtonPushed(app, event)
            app.ex = loadInstruments(app.ex,'pulseBlaster',app.PulseBlasterConfig.Value,false);
            AppUpdate(app)
      end

      % Button pushed function: PulseBlasterUnload
      function PulseBlasterUnloadButtonPushed(app, event)
            disconnect(app.ex.pulseBlaster)
            AppUpdate(app)
      end

      % Button pushed function: SproutLaserLoad
      function SproutLaserLoadButtonPushed(app, event)
            % app = loadInstrument(app,'ndYAG','laser');
            %AppUpdate(app)
      end

      % Button pushed function: SproutLaserUnload
      function SproutLaserUnloadButtonPushed(app, event)
            % CleanUp('laser')
            % AppUpdate(app)
      end

      % Button pushed function: SRSRFLoad
      function SRSRFLoadButtonPushed(app, event)
            app.ex = loadInstruments(app.ex,'SRS_RF',app.SRSRFConfig.Value,false);
            AppUpdate(app)
      end

      % Button pushed function: SRSRFUnload
      function SRSRFUnloadButtonPushed(app, event)
            disconnect(app.ex.SRS_RF)
            AppUpdate(app)
      end

      % Button pushed function: WindfreakRFLoad
      function WindfreakRFLoadButtonPushed(app, event)
             app.ex = loadInstruments(app.ex,'windfreak_RF',app.WindfreakRFConfig.Value);
             AppUpdate(app)
      end

      % Button pushed function: WindfreakRFUnload
      function WindfreakRFUnloadButtonPushed(app, event)
            disconnect(app.ex.windfreak_RF)
            AppUpdate(app)
      end

      % Button pushed function: DAQLoad
      function DAQLoadButtonPushed(app, event)
            app.ex = loadInstruments(app.ex,'DAQ',app.DAQConfig.Value);
            AppUpdate(app)
      end

      % Button pushed function: DAQUnload
      function DAQUnloadButtonPushed(app, event)
            disconnect(app.ex.DAQ) 
            AppUpdate(app)
      end

      % Value changed function: OffWhenDeleted
      function OffWhenDeletedValueChanged(app, event)
            %offWhenDeleted is whether the laser should turn off when its object is deleted
            % if ~checkInstrumentStatus(app,'ndYAG'), return; end
            % 
            % app.ex.ndYAG.offWhenDeleted = app.OffWhenDeletedValueChanged.Value;
            GeneralUpdate(app)
      end

      % Value changed function: WaitForLaserEquilibrium
      function WaitForLaserEquilibriumValueChanged(app, event)
            %waitForEquilibrium is a looping function that waits until the actual power read off the laser equals the set
            % %power
            % if ~checkInstrumentStatus(app,'ndYAG'), return; end

            % app.ex.ndYAG.waitForEquilibrium = app.WaitForLaserEquilibriumValueChanged.Value;
            GeneralUpdate(app)
      end

      % Value changed function: SetLaserPower
      function SetLaserPowerValueChanged(app, event)
            % setPower changes the current power setting of the laser
            % if ~checkInstrumentStatus(app,'ndYAG'), return; end
            % 
            % app.ex.ndYAG.setPower = app.SetLaserPowerValueChanged.Value;
            GeneralUpdate(app)
      end

      % Value changed function: ToggleLaser
      function ToggleLaserValueChanged(app, event)
            %Turns laser on or off
            % if ~checkInstrumentStatus(app,'ndYAG'), return; end
            % 
            % if strcmp(app.SetLaserPowerValueChanged.Value,'on')
            %     app.ex.ndYAG.enabled = true;
            % else
            %     app.ex.ndYAG.enabled = false;
            % end
            GeneralUpdate(app)
      end

      % Button pushed function: RefreshLaserPowerReading
      function RefreshLaserPowerReadingButtonPushed(app, event)
            %Obtains the current output reading of the laser
            % if ~checkInstrumentStatus(app,'ndYAG'), return; end
            % 
            % app.ex.ndYAG = queryActualPower(app.ex.ndYAG);
            GeneralUpdate(app)
      end

      % Value changed function: DifferentiatesignalandreferenceCheckBox
      function DifferentiatesignalandreferenceCheckBoxValueChanged(app, event)
            %Toggles differentiation between signal and reference. If s/r differentiation is off, taking data will be more
            %efficient but unable to produce contrast as only a signle value will be generated
            if ~checkInstrumentStatus(app,'DAQ'), return; end

            app.ex.DAQ.differentiateSignal = app.DifferentiatesignalandreferenceCheckBoxValueChanged.Value;
            GeneralUpdate(app)
      end

      % Value changed function: XPosition
      function XPositionValueChanged(app, event)
            %If stage is loaded, moves stage to desired absolute position
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = absoluteMove(app.ex.PIstage,'x',app.XPosition.Value);
            PositionUpdate(app)
      end

      % Value changed function: YPosition
      function YPositionValueChanged(app, event)
            %If stage is loaded, moves stage to desired absolute position
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = absoluteMove(app.ex.PIstage,'y',app.YPosition.Value);
            PositionUpdate(app)
      end

      % Value changed function: ZPosition
      function ZPositionValueChanged(app, event)
            %If stage is loaded, moves stage to desired absolute position
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = absoluteMove(app.ex.PIstage,'z',app.ZPosition.Value);
            PositionUpdate(app)
      end

      % Changes arrangement of the app based on UIFigure width
      function updateAppLayout(app, event)
         currentFigureWidth = app.UIFigure.Position(3);
         if(currentFigureWidth <= app.onePanelWidth)
             % Change to a 2x1 grid
             app.GridLayout.RowHeight = {813, 813};
             app.GridLayout.ColumnWidth = {'1x'};
             app.RightPanel.Layout.Row = 2;
             app.RightPanel.Layout.Column = 1;
         else
             % Change to a 1x2 grid
             app.GridLayout.RowHeight = {'1x'};
             app.GridLayout.ColumnWidth = {672, '1x'};
             app.RightPanel.Layout.Row = 1;
             app.RightPanel.Layout.Column = 2;
         end
      end

      % Button pushed function: XDown
      function XDownButtonPushed(app, event)
            %If stage is loaded, moves stage by negative increment value
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = relativeMove(app.ex.PIstage,'x',-app.XIncrement.Value);
            PositionUpdate(app)
      end

      % Button pushed function: YDown
      function YDownButtonPushed(app, event)
            %If stage is loaded, moves stage by negative increment value
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = relativeMove(app.ex.PIstage,'y',-app.YIncrement.Value);
            PositionUpdate(app)
      end

      % Button pushed function: ZDown
      function ZDownButtonPushed(app, event)
            %If stage is loaded, moves stage by negative increment value
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = relativeMove(app.ex.PIstage,'z',-app.ZIncrement.Value);
            PositionUpdate(app)
      end

      % Button pushed function: XUp
      function XUpButtonPushed(app, event)
            %If stage is loaded, moves stage by positive increment value
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = relativeMove(app.ex.PIstage,'x',app.XIncrement.Value);
            PositionUpdate(app)
      end

      % Button pushed function: YUp
      function YUpButtonPushed(app, event)
            %If stage is loaded, moves stage by positive increment value
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = relativeMove(app.ex.PIstage,'y',app.YIncrement.Value);
            PositionUpdate(app)
      end

      % Button pushed function: ZUp
      function ZUpButtonPushed(app, event)
            %If stage is loaded, moves stage by positive increment value
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = relativeMove(app.ex.PIstage,'z',app.ZIncrement.Value);
            PositionUpdate(app)
      end

      % Button pushed function: XFineReset
      function XFineResetPushed(app, event)
            %If stage is loaded, resets fine stage to center and moves coarse stage to compensate
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = fineReset(app.ex.PIstage,'x','middle');
            PositionUpdate(app)
      end

      % Button pushed function: YFineReset
      function YFineResetButtonPushed(app, event)
            %If stage is loaded, resets fine stage to center and moves coarse stage to compensate
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = fineReset(app.ex.PIstage,'y','middle');
            PositionUpdate(app)
      end

      % Button pushed function: ZFineReset
      function ZFineResetButtonPushed(app, event)
            %If stage is loaded, resets fine stage to center and moves coarse stage to compensate
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage = fineReset(app.ex.PIstage,'z','middle');
            PositionUpdate(app)
      end

      % Value changed function: StageTolerance
      function StageToleranceValueChanged(app, event)
            %If stage is loaded, changes maximum tolerance for stage movements i.e. how far can the stage's measured
            %distance be from the target location
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage.tolerance = app.StageTolerance.Value;
            PositionUpdate(app)
      end

      % Value changed function: StageSettlingTime
      function StageSettlingTimeValueChanged(app, event)
            %If stage is loaded, changes time stage pauses for every movement
            if ~checkInstrumentStatus(app,'PIstage'), return; end

            app.ex.PIstage.pauseTime = app.StageSettlingTime.Value;
            InfrequentUpdate(app)
      end

      % Button pushed function: AddOptimizationStep
      function AddOptimizationStepPushed(app, event)
            %Adds additional step whose settings can then be altered in the optimization table

            %Default values
            pulseInfo.sequence = "x";
            pulseInfo.radius = 1;
            pulseInfo.steps = 5;

            %Adds new step at given address
            app.optimizationInfo = instrumentType.addStructIndex(app.optimizationInfo,pulseInfo,app.StageAddress);

            PositionUpdate(app)
      end

      % Cell edit callback: StageOptimizationInstructions
      function StageOptimizationInstructionsCellEdit(app, event)
            switch event.Indices(2) %column (stage/radius/steps)

                case 1 %stage
                    if any(cellfun(@(a)strcmpi(a,event.NewData),{"x","y","z"}))
                        app.optimizationInfo(event.Indices(1)).sequence = convertCharsToStrings(event.NewData);
                    else
                        errordlg('Fuser stage must be x, y, or z','Improper Axis')
                    end
                case 2 %radius
                    app.optimizationInfo(event.Indices(1)).radius = str2double(event.NewData);
                case 3 %steps
                    if rem(str2double(event.NewData),2) == 1
                        app.optimizationInfo(event.Indices(1)).steps = str2double(event.NewData);
                    else
                        errordlg('Optimization number of steps must be odd','Must Be Odd')
                    end

            end
            PositionUpdate(app)
      end

      % Button pushed function: AddScanStep
      function AddScanStepPushed(app, event)
            %Note: I chose to have a 1x1 structure with 1xN elements as it
            %is functionally the same except it is easier to deal with
            %individual elements this way

            address = app.ScanAddress.Value;
            currentLength = length(app.ex.scan.type);

            if address > currentLength + 1
                address = currentLength + 1;
                warnMessage = sprintf('Address greater than possible length, changed to %d',address);
                warndlg(warnMessage,'Over Maximum Address')
            end

            fieldsToChange = {'type','start','end','nSteps','stepSize','completed','stepToView'};
            fieldValues = {"Stage X",1,10,10,1,0,1};
            app.ex.scan = ModifyAllFields(app,app.ex.scan,fieldsToChange,fieldValues,address);

            ScanUpdate(app)
            OutputSettingsUpdate(app) %Needed for step to view selection and the like
      end

      % Value changed function: windfreakRFFrequency
      function windfreakRFFrequencyValueChanged(app, event)
            %Changes frequency of windfreak RF output
            if ~checkInstrumentStatus(app,'windfreak_RF'), return; end

            app.ex.windfreak_RF.frequency = app.RFFrequency.Value;
            GeneralUpdate(app)
      end

      % Value changed function: windfreakRFAmplitude
      function windfreakRFAmplitudeValueChanged(app, event)
            %Changes amplitude of windfreak RF output
            if ~checkInstrumentStatus(app,'windfreak_RF'), return; end

            app.ex.windfreak_RF.amplitude = app.windfreakRFAmplitude.Value;
            GeneralUpdate(app)
      end

      % Value changed function: windfreakRFModulationDropDown
      function WindfreakRFwindfreakRFModulationDropDownValueChanged(app, event)
            if ~checkInstrumentStatus(app,'windfreak_RF'), return; end
            if strcmpi("off",app.SRS_RFModulationDropDown.Value)
               app.ex.windfreak_RF.modulationEnabled = "Off";
            else
               app.ex.windfreak_RF.modulationEnabled = "On";
               app.ex.windfreak_RF.modulationType = app.SRS_RFModulationDropDown.Value;
            end
            GeneralUpdate(app)
      end

      % Value changed function: SRS_RFFrequency
      function SRS_RFFrequencyValueChanged(app, event)
            if ~checkInstrumentStatus(app,'SRS_RF'), return; end

            app.ex.SRS_RF.frequency = app.SRS_RFFrequency.Value;
            fprintf('RF frequency set to %s/n',app.ex.SRS_RF.frequency)
            GeneralUpdate(app)
      end

      % Value changed function: SRS_RFAmplitude
      function SRS_RFAmplitudeValueChanged(app, event)
            if ~checkInstrumentStatus(app,'SRS_RF'), return; end

            app.ex.SRS_RF.amplitude = app.SRS_RFAmplitude.Value;
            GeneralUpdate(app)
      end

      % Value changed function: SRS_RFModulationDropDown
      function SRS_RFModulationDropDownValueChanged(app, event)
            if ~checkInstrumentStatus(app,'SRS_RF'), return; end
            if strcmpi("off",app.SRS_RFModulationDropDown.Value)
               app.ex.SRS_RF.modulationEnabled = "Off";
            else
               app.ex.SRS_RF.modulationEnabled = "On";
               app.ex.SRS_RF.modulationType = app.SRS_RFModulationDropDown.Value;
            end
            GeneralUpdate(app)
      end

      % Button pushed function: LaunchPulseSequenceEditorButton
      function LaunchPulseSequenceEditorButtonPushed(app, event)
            %Pulse sequence editor should work with unconnected pulse_blaster object
            %A persistent function should be used to transfer data between that app and this one
            %Only the user sequence and number of loops should be transferred
            %Option to set by number of seconds rather than number of loops within new app**

            PulseSequenceEditor()
      end

      % Value changed function: StageAutoOptimization
      function StageAutoOptimizationValueChanged(app, event)
            app.StageAutoOptimization.Value = app.StageAutoOptimization.Value;
      end

      % Value changed function: OptimizationmethodDropDown
      function OptimizationmethodDropDownValueChanged(app, event)
            if strcmpi("Raw value", app.OptimizationmethodDropDown.Value)
                app.ex.optimizationInfo.algorithmType = 'max value';
            else
                app.ex.optimizationInfo.algorithmType = 'gaussian';
            end
      end

      % Value changed function: StageOptimizationInterval
      function StageOptimizationIntervalValueChanged(app, event)
            app.ex.optimizationInfo.timeBetweenOptimizations = app.StageOptimizationInterval.Value;
      end

      % Value changed function: OptimizationparameterDropDown
      function OptimizationparameterDropDownValueChanged(app, event)
            if strcmpi("Conuts (RF on)", app.OptimizationparameterDropDown.Value)
                app.ex.optimizationInfo.rfStatus = 'on';
            elseif strcmpi("Counts (RF off)",app.OptimizationparameterDropDown.Value)
                app.ex.optimizationInfo.rfStatus = 'off';
            else %contrast
                app.ex.optimizationInfo.rfStatus = 'contrast';
            end
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'MATLAB App';
         app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
         app.GridLayout = uigridlayout(app.UIFigure);
         app.LeftPanel = uipanel(app.GridLayout);
         app.TabGroup = uitabgroup(app.LeftPanel);
         app.ConnectionsTab = uitab(app.TabGroup);
         app.ConnectionsTab.Title = 'Connections';
         app.GridLayout2 = uigridlayout(app.ConnectionsTab);
         app.StageLoad = uibutton(app.GridLayout2, 'push');
         app.StageLoad.ButtonPushedFcn = createCallbackFcn(app, @StageLoadButtonPushed, true);
         app.StageLoad.Text = 'Load';
         app.StageUnload = uibutton(app.GridLayout2, 'push');
         app.StageUnload.ButtonPushedFcn = createCallbackFcn(app, @StageUnloadButtonPushed, true);
         app.StageUnload.Text = 'Unload';
         app.StageLamp = uilamp(app.GridLayout2);
         app.PulseBlasterLoad = uibutton(app.GridLayout2, 'push');
         app.PulseBlasterLoad.ButtonPushedFcn = createCallbackFcn(app, @PulseBlasterLoadButtonPushed, true);
         app.PulseBlasterLoad.Text = 'Load';
         app.SRSRFLoad = uibutton(app.GridLayout2, 'push');
         app.SRSRFLoad.ButtonPushedFcn = createCallbackFcn(app, @SRSRFLoadButtonPushed, true);
         app.SRSRFLoad.Text = 'Load';
         app.PulseBlasterLamp = uilamp(app.GridLayout2);
         app.SRSRFLamp = uilamp(app.GridLayout2);
         app.DAQLamp = uilamp(app.GridLayout2);
         app.PulseBlasterUnload = uibutton(app.GridLayout2, 'push');
         app.PulseBlasterUnload.ButtonPushedFcn = createCallbackFcn(app, @PulseBlasterUnloadButtonPushed, true);
         app.PulseBlasterUnload.Text = 'Unload';
         app.SRSRFUnload = uibutton(app.GridLayout2, 'push');
         app.SRSRFUnload.ButtonPushedFcn = createCallbackFcn(app, @SRSRFUnloadButtonPushed, true);
         app.SRSRFUnload.Text = 'Unload';
         app.DAQLoad = uibutton(app.GridLayout2, 'push');
         app.DAQLoad.ButtonPushedFcn = createCallbackFcn(app, @DAQLoadButtonPushed, true);
         app.DAQLoad.Text = 'Load';
         app.DAQUnload = uibutton(app.GridLayout2, 'push');
         app.DAQUnload.ButtonPushedFcn = createCallbackFcn(app, @DAQUnloadButtonPushed, true);
         app.DAQUnload.Text = 'Unload';
         app.SproutLaserLoad = uibutton(app.GridLayout2, 'push');
         app.SproutLaserLoad.ButtonPushedFcn = createCallbackFcn(app, @SproutLaserLoadButtonPushed, true);
         app.SproutLaserLoad.Text = 'Load';
         app.SproutLaserUnload = uibutton(app.GridLayout2, 'push');
         app.SproutLaserUnload.ButtonPushedFcn = createCallbackFcn(app, @SproutLaserUnloadButtonPushed, true);
         app.SproutLaserUnload.Text = 'Unload';
         app.SproutLaserLamp = uilamp(app.GridLayout2);
         app.StageLabel = uilabel(app.GridLayout2);
         app.StageLabel.Text = 'Stage';
         app.PulseBlasterLabel = uilabel(app.GridLayout2);
         app.PulseBlasterLabel.Text = 'Pulse Blaster';
         app.SproutLaserLabel = uilabel(app.GridLayout2);
         app.SproutLaserLabel.Text = 'Sprout Laser';
         app.SRSRFLabel = uilabel(app.GridLayout2);
         app.SRSRFLabel.Text = 'SRS RF';
         app.DAQLabel = uilabel(app.GridLayout2);
         app.DAQLabel.Text = 'DAQ';
         app.ConfigFileNameLabel = uilabel(app.GridLayout2);
         app.ConfigFileNameLabel.Text = 'Config File Name';
         app.StageConfig = uieditfield(app.GridLayout2, 'text');
         app.PulseBlasterConfig = uieditfield(app.GridLayout2, 'text');
         app.SproutLaserConfig = uieditfield(app.GridLayout2, 'text');
         app.SRSRFConfig = uieditfield(app.GridLayout2, 'text');
         app.DAQConfig = uieditfield(app.GridLayout2, 'text');
         app.WindfreakRFLabel = uilabel(app.GridLayout2);
         app.WindfreakRFLabel.Text = 'Windfreak RF';
         app.WindfreakRFConfig = uieditfield(app.GridLayout2, 'text');
         app.WindfreakRFUnload = uibutton(app.GridLayout2, 'push');
         app.WindfreakRFUnload.ButtonPushedFcn = createCallbackFcn(app, @WindfreakRFUnloadButtonPushed, true);
         app.WindfreakRFUnload.Text = 'Unload';
         app.WindfreakRFLamp = uilamp(app.GridLayout2);
         app.WindfreakRFLoad = uibutton(app.GridLayout2, 'push');
         app.WindfreakRFLoad.ButtonPushedFcn = createCallbackFcn(app, @WindfreakRFLoadButtonPushed, true);
         app.WindfreakRFLoad.Text = 'Load';
         app.SettingsTab = uitab(app.TabGroup);
         app.SettingsTab.Title = 'Settings';
         app.GridLayout4 = uigridlayout(app.SettingsTab);
         app.SetLaserPower = uieditfield(app.GridLayout4, 'numeric');
         app.SetLaserPower.ValueChangedFcn = createCallbackFcn(app, @SetLaserPowerValueChanged, true);
         app.LaserLabel_2 = uilabel(app.GridLayout4);
         app.LaserLabel_2.Text = 'Sprout Laser';
         app.ToggleLaser = uiswitch(app.GridLayout4, 'slider');
         app.ToggleLaser.Items = {'off', 'on'};
         app.ToggleLaser.ValueChangedFcn = createCallbackFcn(app, @ToggleLaserValueChanged, true);
         app.ToggleLaser.Value = 'off';
         app.RefreshAppButton = uibutton(app.GridLayout4, 'push');
         app.RefreshAppButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshAppButtonPushed, true);
         app.RefreshAppButton.Text = 'Refresh App';
         app.SetpowermWLabel = uilabel(app.GridLayout4);
         app.SetpowermWLabel.Text = {'Set power'; '(mW)'};
         app.PowerreadingmWLabel = uilabel(app.GridLayout4);
         app.PowerreadingmWLabel.Text = {'Power'; 'reading (mW)'};
         app.LaserPowerReading = uieditfield(app.GridLayout4, 'numeric');
         app.LaserPowerReading.Editable = 'off';
         app.RefreshLaserPowerReading = uibutton(app.GridLayout4, 'push');
         app.RefreshLaserPowerReading.ButtonPushedFcn = createCallbackFcn(app, @RefreshLaserPowerReadingButtonPushed, true);
         app.RefreshLaserPowerReading.Text = {'Refresh'; 'power'; 'reading'};
         app.CollectdatacontinuouslyButton = uibutton(app.GridLayout4, 'push');
         app.CollectdatacontinuouslyButton.ButtonPushedFcn = createCallbackFcn(app, @CollectdatacontinuouslyButtonPushed, true);
         app.CollectdatacontinuouslyButton.Text = {'Collect data'; 'continuously'};
         app.CopyappobjecttoworkspaceButton = uibutton(app.GridLayout4, 'push');
         app.CopyappobjecttoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @CopyappobjecttoworkspaceButtonPushed, true);
         app.CopyappobjecttoworkspaceButton.Text = {'Copy app object '; 'to workspace'};
         app.SRS_RFFrequency = uieditfield(app.GridLayout4, 'numeric');
         app.SRS_RFFrequency.ValueChangedFcn = createCallbackFcn(app, @SRS_RFFrequencyValueChanged, true);
         app.SRS_RFAmplitude = uieditfield(app.GridLayout4, 'numeric');
         app.SRS_RFAmplitude.ValueChangedFcn = createCallbackFcn(app, @SRS_RFAmplitudeValueChanged, true);
         app.DifferentiatesignalandreferenceCheckBox = uicheckbox(app.GridLayout4);
         app.DifferentiatesignalandreferenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @DifferentiatesignalandreferenceCheckBoxValueChanged, true);
         app.DifferentiatesignalandreferenceCheckBox.Text = {'  Differentiate '; '  signal and'; '  reference'};
         app.LaunchPulseSequenceEditorButton = uibutton(app.GridLayout4, 'push');
         app.LaunchPulseSequenceEditorButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchPulseSequenceEditorButtonPushed, true);
         app.LaunchPulseSequenceEditorButton.Text = {'Launch Pulse'; 'Sequence Editor'};
         app.WaitForLaserEquilibrium = uicheckbox(app.GridLayout4);
         app.WaitForLaserEquilibrium.ValueChangedFcn = createCallbackFcn(app, @WaitForLaserEquilibriumValueChanged, true);
         app.WaitForLaserEquilibrium.Text = {'  Wait for laser'; '  equilibrium'};
         app.OffWhenDeleted = uicheckbox(app.GridLayout4);
         app.OffWhenDeleted.ValueChangedFcn = createCallbackFcn(app, @OffWhenDeletedValueChanged, true);
         app.OffWhenDeleted.Text = {'  Turn laser off'; '  when unloaded'};
         app.SRS_RFModulationDropDown = uidropdown(app.GridLayout4);
         app.SRS_RFModulationDropDown.Items = {'Off', 'I/Q', 'amplitude'};
         app.SRS_RFModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @SRS_RFModulationDropDownValueChanged, true);
         app.SRS_RFModulationDropDown.Value = 'Off';
         app.NumberofpointsforcontinuouscollectionLabel = uilabel(app.GridLayout4);
         app.NumberofpointsforcontinuouscollectionLabel.Text = {'Number of points for '; 'continuous collection'};
         app.NumberofpointsforcontinuouscollectionEditField = uieditfield(app.GridLayout4, 'numeric');
         app.RFFrequencyGHzLabel_2 = uilabel(app.GridLayout4);
         app.RFFrequencyGHzLabel_2.Text = 'Frequency (GHz)      ';
         app.windfreakRFFrequency = uieditfield(app.GridLayout4, 'numeric');
         app.windfreakRFFrequency.ValueChangedFcn = createCallbackFcn(app, @windfreakRFFrequencyValueChanged, true);
         app.windfreakRFAmplitude = uieditfield(app.GridLayout4, 'numeric');
         app.windfreakRFAmplitude.ValueChangedFcn = createCallbackFcn(app, @windfreakRFAmplitudeValueChanged, true);
         app.ModulationLabel = uilabel(app.GridLayout4);
         app.ModulationLabel.Text = 'Modulation     ';
         app.windfreakRFModulationDropDown = uidropdown(app.GridLayout4);
         app.windfreakRFModulationDropDown.Items = {'Off', 'I/Q', 'amplitude'};
         app.windfreakRFModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @WindfreakRFwindfreakRFModulationDropDownValueChanged, true);
         app.windfreakRFModulationDropDown.Value = 'Off';
         app.SRSRFsettingsLabel = uilabel(app.GridLayout4);
         app.SRSRFsettingsLabel.Text = 'SRS RF settings';
         app.WindfreakRFsettingsLabel = uilabel(app.GridLayout4);
         app.WindfreakRFsettingsLabel.Text = 'Windfreak RF settings';
         app.AmplitudedBmLabel = uilabel(app.GridLayout4);
         app.AmplitudedBmLabel.Text = 'Amplitude (dBm)';
         app.PositionTab = uitab(app.TabGroup);
         app.PositionTab.Title = 'Position';
         app.GridLayout3 = uigridlayout(app.PositionTab);
         app.XDown = uibutton(app.GridLayout3, 'push');
         app.XDown.ButtonPushedFcn = createCallbackFcn(app, @XDownButtonPushed, true);
         app.XDown.Text = '<';
         app.XUp = uibutton(app.GridLayout3, 'push');
         app.XUp.ButtonPushedFcn = createCallbackFcn(app, @XUpButtonPushed, true);
         app.XUp.Text = '>';
         app.XIncrement = uieditfield(app.GridLayout3, 'numeric');
         app.XPosition = uieditfield(app.GridLayout3, 'numeric');
         app.XPosition.Limits = [-12600 12600];
         app.XPosition.ValueChangedFcn = createCallbackFcn(app, @XPositionValueChanged, true);
         app.AbsolutepositionLabel = uilabel(app.GridLayout3);
         app.AbsolutepositionLabel.Text = {'Absolute'; 'position'};
         app.IncrementLabel = uilabel(app.GridLayout3);
         app.IncrementLabel.Text = 'Increment';
         app.YIncrement = uieditfield(app.GridLayout3, 'numeric');
         app.YPosition = uieditfield(app.GridLayout3, 'numeric');
         app.YPosition.Limits = [-12600 12600];
         app.YPosition.ValueChangedFcn = createCallbackFcn(app, @YPositionValueChanged, true);
         app.StagetolerancemLabel = uilabel(app.GridLayout3);
         app.StagetolerancemLabel.Text = {'Stage'; 'tolerance'; '(μm)'};
         app.StageTolerance = uieditfield(app.GridLayout3, 'numeric');
         app.StageTolerance.Limits = [0 Inf];
         app.StageTolerance.ValueChangedFcn = createCallbackFcn(app, @StageToleranceValueChanged, true);
         app.StageTolerance.Value = 0.01;
         app.AxisLabel = uilabel(app.GridLayout3);
         app.AxisLabel.Text = 'Axis';
         app.StagexLabel = uilabel(app.GridLayout3);
         app.StagexLabel.Text = 'Stage x';
         app.StageyLabel = uilabel(app.GridLayout3);
         app.StageyLabel.Text = 'Stage y';
         app.StagezLabel = uilabel(app.GridLayout3);
         app.StagezLabel.Text = 'Stage z';
         app.MovenegativeLabel = uilabel(app.GridLayout3);
         app.MovenegativeLabel.Text = {'Move'; 'negative'};
         app.MovepositiveLabel = uilabel(app.GridLayout3);
         app.MovepositiveLabel.Text = {'Move'; 'positive'};
         app.YDown = uibutton(app.GridLayout3, 'push');
         app.YDown.ButtonPushedFcn = createCallbackFcn(app, @YDownButtonPushed, true);
         app.YDown.Text = '<';
         app.ZDown = uibutton(app.GridLayout3, 'push');
         app.ZDown.ButtonPushedFcn = createCallbackFcn(app, @ZDownButtonPushed, true);
         app.ZDown.Text = '<';
         app.YUp = uibutton(app.GridLayout3, 'push');
         app.YUp.ButtonPushedFcn = createCallbackFcn(app, @YUpButtonPushed, true);
         app.YUp.Text = '>';
         app.ZUp = uibutton(app.GridLayout3, 'push');
         app.ZUp.ButtonPushedFcn = createCallbackFcn(app, @ZUpButtonPushed, true);
         app.ZUp.Text = '>';
         app.ZPosition = uieditfield(app.GridLayout3, 'numeric');
         app.ZPosition.Limits = [-12600 12600];
         app.ZPosition.ValueChangedFcn = createCallbackFcn(app, @ZPositionValueChanged, true);
         app.ZIncrement = uieditfield(app.GridLayout3, 'numeric');
         app.AddressEditField_2Label = uilabel(app.GridLayout3);
         app.AddressEditField_2Label.Text = 'Address';
         app.StageAddress = uieditfield(app.GridLayout3, 'numeric');
         app.StageAddress.Limits = [1 Inf];
         app.StageAddress.Value = 1;
         app.StageOptimizationInstructions = uitable(app.GridLayout3);
         app.StageOptimizationInstructions.ColumnName = {'Stage'; 'Radius'; 'Steps'};
         app.StageOptimizationInstructions.RowName = {};
         app.StageOptimizationInstructions.CellEditCallback = createCallbackFcn(app, @StageOptimizationInstructionsCellEdit, true);
         app.AddOptimizationStep = uibutton(app.GridLayout3, 'push');
         app.AddOptimizationStep.ButtonPushedFcn = createCallbackFcn(app, @AddOptimizationStepPushed, true);
         app.AddOptimizationStep.Text = {'Add'; 'step'};
         app.DeleteOptimizationStep = uibutton(app.GridLayout3, 'push');
         app.DeleteOptimizationStep.ButtonPushedFcn = createCallbackFcn(app, @DeleteOptimizationStepPushed, true);
         app.DeleteOptimizationStep.Text = {'Delete'; 'step'};
         app.StageAutoOptimization = uicheckbox(app.GridLayout3);
         app.StageAutoOptimization.ValueChangedFcn = createCallbackFcn(app, @StageAutoOptimizationValueChanged, true);
         app.StageAutoOptimization.Text = {'Auto-optimize'; 'during scan'};
         app.TimebetweenoptimizationsminLabel = uilabel(app.GridLayout3);
         app.TimebetweenoptimizationsminLabel.Text = {'Time between'; 'optimizations (min)'};
         app.StageOptimizationInterval = uieditfield(app.GridLayout3, 'numeric');
         app.StageOptimizationInterval.ValueChangedFcn = createCallbackFcn(app, @StageOptimizationIntervalValueChanged, true);
         app.BeginmanualoptimizationButton = uibutton(app.GridLayout3, 'push');
         app.BeginmanualoptimizationButton.Text = 'Begin manual optimization';
         app.NumberofmanualoptimizationsEditFieldLabel = uilabel(app.GridLayout3);
         app.NumberofmanualoptimizationsEditFieldLabel.Text = {'Number of '; 'manual optimizations'};
         app.NumberofmanualoptimizationsEditField = uieditfield(app.GridLayout3, 'numeric');
         app.OptimizationmethodDropDownLabel = uilabel(app.GridLayout3);
         app.OptimizationmethodDropDownLabel.Text = {'Optimization'; 'method'};
         app.OptimizationmethodDropDown = uidropdown(app.GridLayout3);
         app.OptimizationmethodDropDown.Items = {'Raw value', 'Gaussian peak'};
         app.OptimizationmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationmethodDropDownValueChanged, true);
         app.OptimizationmethodDropDown.Value = 'Raw value';
         app.PausedurationforfinestagemovementsmsLabel = uilabel(app.GridLayout3);
         app.PausedurationforfinestagemovementsmsLabel.Text = {'Settling duration'; 'for stage'; 'movements (ms)'};
         app.StageSettlingTime = uieditfield(app.GridLayout3, 'numeric');
         app.StageSettlingTime.ValueChangedFcn = createCallbackFcn(app, @StageSettlingTimeValueChanged, true);
         app.ResetFineAxisLabel = uilabel(app.GridLayout3);
         app.ResetFineAxisLabel.Text = {'Reset'; 'Fine Axis'};
         app.XFineReset = uibutton(app.GridLayout3, 'push');
         app.XFineReset.ButtonPushedFcn = createCallbackFcn(app, @XFineResetPushed, true);
         app.XFineReset.Text = {'Reset'; 'fine x'};
         app.YFineReset = uibutton(app.GridLayout3, 'push');
         app.YFineReset.ButtonPushedFcn = createCallbackFcn(app, @YFineResetButtonPushed, true);
         app.YFineReset.Text = {'Reset'; 'fine y'};
         app.ZFineReset = uibutton(app.GridLayout3, 'push');
         app.ZFineReset.ButtonPushedFcn = createCallbackFcn(app, @ZFineResetButtonPushed, true);
         app.ZFineReset.Text = {'Reset'; 'fine z'};
         app.OptimizationparameterLabel = uilabel(app.GridLayout3);
         app.OptimizationparameterLabel.Text = {'Optimization'; 'parameter'};
         app.OptimizationparameterDropDown = uidropdown(app.GridLayout3);
         app.OptimizationparameterDropDown.Items = {'Counts (RF off)', 'Counts (RF on)', 'Contrast'};
         app.OptimizationparameterDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationparameterDropDownValueChanged, true);
         app.OptimizationparameterDropDown.Value = 'Counts (RF off)';
         app.ScansTab = uitab(app.TabGroup);
         app.ScansTab.Title = 'Scans';
         app.GridLayout6 = uigridlayout(app.ScansTab);
         app.ScanningTable = uitable(app.GridLayout6);
         app.ScanningTable.ColumnName = {'Type'; 'Start'; 'End'; '# of steps'; 'Step size'; 'Pulse addresses'};
         app.ScanningTable.RowName = {};
         app.ScanningTable.ColumnEditable = true;
         app.ScanningTable.CellEditCallback = createCallbackFcn(app, @ScanningTableCellEdit, true);
         app.AddScanStep = uibutton(app.GridLayout6, 'push');
         app.AddScanStep.ButtonPushedFcn = createCallbackFcn(app, @AddScanStepPushed, true);
         app.AddScanStep.Text = 'Add scan';
         app.DeleteScanStep = uibutton(app.GridLayout6, 'push');
         app.DeleteScanStep.ButtonPushedFcn = createCallbackFcn(app, @DeleteScanStepPushed, true);
         app.DeleteScanStep.Text = 'Delete scan';
         app.AddressEditField_3Label = uilabel(app.GridLayout6);
         app.AddressEditField_3Label.Text = 'Address';
         app.ScanAddress = uieditfield(app.GridLayout6, 'numeric');
         app.ScanAddress.Limits = [1 3];
         app.ScanAddress.Value = 1;
         app.DefaultchangeDropDownLabel = uilabel(app.GridLayout6);
         app.DefaultchangeDropDownLabel.Text = 'Default change';
         app.DefaultScanChange = uidropdown(app.GridLayout6);
         app.DefaultScanChange.Items = {'# of steps', 'Step size'};
         app.DefaultScanChange.Value = '# of steps';
         app.PossibletypesTextAreaLabel = uilabel(app.GridLayout6);
         app.PossibletypesTextAreaLabel.Text = 'Possible types';
         app.PossibletypesTextArea = uitextarea(app.GridLayout6);
         app.PossibletypesTextArea.Editable = 'off';
         app.PossibletypesTextArea.Value = {'Stage X'; 'Stage Y'; 'Stage Z'};
         app.RandomizescanCheckBox = uicheckbox(app.GridLayout6);
         app.RandomizescanCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomizescanCheckBoxValueChanged, true);
         app.RandomizescanCheckBox.Text = 'Randomize scan';
         app.MaxIterationsEditFieldLabel = uilabel(app.GridLayout6);
         app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
         app.MaxIterations = uieditfield(app.GridLayout6, 'numeric');
         app.MaxIterations.Limits = [0 Inf];
         app.MaxIterations.Value = 1;
         app.IterationstypeDropDownLabel = uilabel(app.GridLayout6);
         app.IterationstypeDropDownLabel.Text = 'Iterations type';
         app.IterationstypeDropDown = uidropdown(app.GridLayout6);
         app.IterationstypeDropDown.Items = {'Number', 'Time (min)', 'Perpetual'};
         app.IterationstypeDropDown.Value = 'Number';
         app.NonlinearscanfilenameLabel = uilabel(app.GridLayout6);
         app.NonlinearscanfilenameLabel.Text = {'Nonlinear scan'; 'file name'};
         app.NonlinearscanfilenameEditField = uieditfield(app.GridLayout6, 'text');
         app.OutputTab = uitab(app.TabGroup);
         app.OutputTab.Title = 'Output';
         app.GridLayout5 = uigridlayout(app.OutputTab);
         app.PlotLimits = uitable(app.GridLayout5);
         app.PlotLimits.ColumnName = {'Axis'; 'Minimum'; 'Maximum'; 'Override'; 'Offset'; 'Incorporate Offset'};
         app.PlotLimits.RowName = {};
         app.PlotLimits.ColumnEditable = [false true true true];
         app.PlotLimits.CellEditCallback = createCallbackFcn(app, @PlotLimitsCellEdit, true);
         app.ScanStepTable = uitable(app.GridLayout5);
         app.ScanStepTable.ColumnName = {'Scan Type'; 'Step to View'};
         app.ScanStepTable.RowName = {};
         app.ScanStepTable.ColumnEditable = [false true];
         app.ScanStepTable.CellEditCallback = createCallbackFcn(app, @ScanStepTableCellEdit, true);
         app.ScanProgress = uitextarea(app.GridLayout5);
         app.ScanProgress.Editable = 'off';
         app.ScanProgress.Value = {'Shows completion of each scan. If it is a randomized scan, shows linear completion'};
         app.ForcesquareaspectratioforimagesCheckBox = uicheckbox(app.GridLayout5);
         app.ForcesquareaspectratioforimagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForcesquareaspectratioforimagesCheckBoxValueChanged, true);
         app.ForcesquareaspectratioforimagesCheckBox.Text = {'Force square aspect ratio'; 'for images'};
         app.DropDown = uidropdown(app.GridLayout5);
         app.DropDown.Items = {'Display average across iterations', 'Display current iteration', 'Display prior iterations'};
         app.DropDown.ItemsData = {'Average', 'Current', 'Prior'};
         app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
         app.DropDown.Value = 'Average';
         app.UseStaticColorLimits = uicheckbox(app.GridLayout5);
         app.UseStaticColorLimits.ValueChangedFcn = createCallbackFcn(app, @UseStaticColorLimitsValueChanged, true);
         app.UseStaticColorLimits.Text = 'Use static color limits';
         app.StaticColorLimits = uitable(app.GridLayout5);
         app.StaticColorLimits.ColumnName = {'Static color limit'; 'Value'};
         app.StaticColorLimits.RowName = {};
         app.StaticColorLimits.ColumnEditable = [false true];
         app.StaticColorLimits.CellEditCallback = createCallbackFcn(app, @StaticColorLimitsCellEdit, true);
         app.ScannumberofxaxisEditFieldLabel = uilabel(app.GridLayout5);
         app.ScannumberofxaxisEditFieldLabel.Text = 'Scan number of x axis';
         app.ScannumberofxaxisEditField = uieditfield(app.GridLayout5, 'numeric');
         app.ScannumberofyaxisEditFieldLabel = uilabel(app.GridLayout5);
         app.ScannumberofyaxisEditFieldLabel.Text = 'Scan number of y axis';
         app.ScannumberofyaxisEditField = uieditfield(app.GridLayout5, 'numeric');
         app.ContrasttypeDropDownLabel = uilabel(app.GridLayout5);
         app.ContrasttypeDropDownLabel.Text = 'Contrast type';
         app.ContrasttypeDropDown = uidropdown(app.GridLayout5);
         app.ContrasttypeDropDown.Items = {'Ref - Sig', '(Ref - Sig)/Ref', '(Ref - Sig)/Sig', '(Ref - Sig)/(Ref + Sig)'};
         app.ContrasttypeDropDown.Value = 'Ref - Sig';
         app.ConverttocountssMaynotperfectlycalculateforsmalltimesCheckBox = uicheckbox(app.GridLayout5);
         app.ConverttocountssMaynotperfectlycalculateforsmalltimesCheckBox.Text = {'Convert to counts/s '; '**May not perfectly '; 'calculate for small times'};
         app.ForceplotupdateButton = uibutton(app.GridLayout5, 'push');
         app.ForceplotupdateButton.Text = 'Force plot update';
         app.SaveLoadTab = uitab(app.TabGroup);
         app.SaveLoadTab.Title = 'Save/Load';
         app.GridLayout9 = uigridlayout(app.SaveLoadTab);
         app.BoxaddressEditFieldLabel = uilabel(app.GridLayout9);
         app.BoxaddressEditFieldLabel.Text = 'Box address';
         app.BoxAddress = uieditfield(app.GridLayout9, 'text');
         app.BoxAddress.ValueChangedFcn = createCallbackFcn(app, @BoxAddressValueChanged, true);
         app.SavenameLabel = uilabel(app.GridLayout9);
         app.SavenameLabel.Text = {'Save name'; ''};
         app.SaveFolderName = uieditfield(app.GridLayout9, 'text');
         app.SaveButton = uibutton(app.GridLayout9, 'push');
         app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
         app.SaveButton.Text = 'Save';
         app.LoadButton = uibutton(app.GridLayout9, 'push');
         app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
         app.LoadButton.Text = 'Load';
         app.SavedUserNotes = uitextarea(app.GridLayout9);
         app.SavedUserNotes.Value = {'User notes'; ''};
         app.DataSetupsSwitch = uiswitch(app.GridLayout9, 'slider');
         app.DataSetupsSwitch.Items = {'Data', 'Setups'};
         app.DataSetupsSwitch.Value = 'Data';
         app.LoadDates = uilistbox(app.GridLayout9);
         app.LoadDates.Items = {'Setup Versions', 'v# - yyyy/mm/dd', ''};
         app.LoadDates.Value = 'Setup Versions';
         app.LoadNames = uilistbox(app.GridLayout9);
         app.LoadNames.Items = {'Setup Names', 'any string'};
         app.LoadNames.Value = 'Setup Names';
         app.UserName = uidropdown(app.GridLayout9);
         app.UserName.Items = {};
         app.UserName.Value = {};
         app.RightPanel = uipanel(app.GridLayout);
         app.Panel = uipanel(app.RightPanel);
         app.GridLayout7 = uigridlayout(app.Panel);
         app.StageOptimizationInstructions_2 = uitable(app.GridLayout7);
         app.StageOptimizationInstructions_2.ColumnName = {'Stage'; 'Radius'; 'Steps'};
         app.StageOptimizationInstructions_2.RowName = {};
         app.OutputPanel = uipanel(app.GridLayout7);
         app.StartButton = uibutton(app.GridLayout7, 'push');
         app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
         app.StartButton.Text = 'Start';
         app.ResetButton = uibutton(app.GridLayout7, 'push');
         app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
         app.ResetButton.Text = 'Reset';
         app.OutputInfo = uitextarea(app.GridLayout7);
         app.OutputInfo.Editable = 'off';
         app.ColorBarPanel = uipanel(app.GridLayout7);
         app.StatusLamp = uilamp(app.GridLayout7);
         app.ScreenshotButton = uibutton(app.GridLayout7, 'push');
         app.ScreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @ScreenshotButtonPushed, true);
         app.ScreenshotButton.Text = 'Screenshot';
         app.AbortButton = uibutton(app.GridLayout7, 'push');
         app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
         app.AbortButton.Text = 'Abort';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = NV_ExperimentEditor

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @startupFunction)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end